classdef sm_car_vehcfg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PresetsTab                      matlab.ui.container.Tab
        SelectVehicleConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        OnePowerShaftButton             matlab.ui.control.RadioButton
        CVsFrontandRearButton           matlab.ui.control.RadioButton
        AntiLockBrakesButton            matlab.ui.control.RadioButton
        NonlinearSpringsFrontButton     matlab.ui.control.RadioButton
        NonlinearSpringsRearButton      matlab.ui.control.RadioButton
        SpringswithNoVariantsButton     matlab.ui.control.RadioButton
        NonlinearDampersRearButton      matlab.ui.control.RadioButton
        NonlinearDampersFrontButton     matlab.ui.control.RadioButton
        CVCVPrismaticFrontButton        matlab.ui.control.RadioButton
        DefaultButton                   matlab.ui.control.RadioButton
        StiffSpringDamperButton         matlab.ui.control.RadioButton
        MFevalTireHambaButton           matlab.ui.control.RadioButton
        SwiftTireHambaButton            matlab.ui.control.RadioButton
        SedanHambaLabel                 matlab.ui.control.Label
        SedanHambaLGLabel               matlab.ui.control.Label
        BusMakhuluLabel                 matlab.ui.control.Label
        BusMakhuluSwiftTireButton       matlab.ui.control.RadioButton
        MFevalTire2xRMakhuluButton      matlab.ui.control.RadioButton
        MFevalTire1xRMakhuluButton      matlab.ui.control.RadioButton
        SwiftTyre1xRMakhuluButton       matlab.ui.control.RadioButton
        LiveAxleMFevalHambaButton       matlab.ui.control.RadioButton
        LiveAxleSwiftHambaButton        matlab.ui.control.RadioButton
        EMotor2xMFevalHambaButton       matlab.ui.control.RadioButton
        EMotor2xSwiftHambaButton        matlab.ui.control.RadioButton
        EMotor3xMFevalHambaButton       matlab.ui.control.RadioButton
        EMotor3xSwiftHambaButton        matlab.ui.control.RadioButton
        DOFMFevalHambaButton            matlab.ui.control.RadioButton
        DOFSwiftHambaButton             matlab.ui.control.RadioButton
        DOFMot2xCoolSwiHambaButton      matlab.ui.control.RadioButton
        DOFMot2xCoolMFHambaButton       matlab.ui.control.RadioButton
        DOFMot3xCoolMFHambaButton       matlab.ui.control.RadioButton
        DOFMot3xCoolSwiHambaButton      matlab.ui.control.RadioButton
        DOFSwiftHamba1DButton           matlab.ui.control.RadioButton
        DOFFuelCellSwiHambaButton       matlab.ui.control.RadioButton
        DOFFuelCellMFHambaButton        matlab.ui.control.RadioButton
        RealTimeConfigBasicButton       matlab.ui.control.RadioButton
        RealTimeConfigFullButton        matlab.ui.control.RadioButton
        MFeval2xRFuelCellMakButton      matlab.ui.control.RadioButton
        Swift2xRFuelCellMakButton       matlab.ui.control.RadioButton
        SelectTrailerConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        NoTrailerButton                 matlab.ui.control.RadioButton
        ElulaMFevalButton               matlab.ui.control.RadioButton
        ThwalaMFevalButton              matlab.ui.control.RadioButton
        ElulaSwiftButton                matlab.ui.control.RadioButton
        ThwalaSwiftButton               matlab.ui.control.RadioButton
        ElulaMFevalUnstableButton       matlab.ui.control.RadioButton
        HelpLabelPresets                matlab.ui.control.Label
        ConfigureTab                    matlab.ui.container.Tab
        AeroDropDownLabel               matlab.ui.control.Label
        AeroDropDown                    matlab.ui.control.DropDown
        BodyDropDownLabel               matlab.ui.control.Label
        BodyDropDown                    matlab.ui.control.DropDown
        PowerDropDownLabel              matlab.ui.control.Label
        PowerDropDown                   matlab.ui.control.DropDown
        BrakesDropDownLabel             matlab.ui.control.Label
        BrakesDropDown                  matlab.ui.control.DropDown
        SpringsDropDownLabel            matlab.ui.control.Label
        SpringsDropDown                 matlab.ui.control.DropDown
        DampersDropDownLabel            matlab.ui.control.Label
        DampersDropDown                 matlab.ui.control.DropDown
        FrontSuspensionDropDownLabel    matlab.ui.control.Label
        FrontSuspensionDropDown         matlab.ui.control.DropDown
        RearSuspensionDropDownLabel     matlab.ui.control.Label
        RearSuspensionDropDown          matlab.ui.control.DropDown
        FrontSteerDropDownLabel         matlab.ui.control.Label
        FrontSteerDropDown              matlab.ui.control.DropDown
        RearSteerDropDownLabel          matlab.ui.control.Label
        RearSteerDropDown               matlab.ui.control.DropDown
        DrivelineDropDownLabel          matlab.ui.control.Label
        DrivelineDropDown               matlab.ui.control.DropDown
        DefineCustomConfigurationLabel  matlab.ui.control.Label
        ABSControlCheckBox              matlab.ui.control.CheckBox
        BodyGeometryDropDownLabel       matlab.ui.control.Label
        BodyGeometryDropDown            matlab.ui.control.DropDown
        OverrideinWorkspaceLabel        matlab.ui.control.Label
        OverrideApplyButtonSprings      matlab.ui.control.Button
        OverrideApplyButtonFrontSteer   matlab.ui.control.Button
        OverrideApplyButtonBodyGeometry  matlab.ui.control.Button
        OverrideApplyButtonDampers      matlab.ui.control.Button
        OverrideApplyButtonPower        matlab.ui.control.Button
        OverrideApplyButtonDriveline    matlab.ui.control.Button
        OverrideApplyButtonFrontSuspension  matlab.ui.control.Button
        OverrideApplyButtonRearSuspension  matlab.ui.control.Button
        OverrideApplyButtonBrakes       matlab.ui.control.Button
        OverrideApplyButtonRearSteer    matlab.ui.control.Button
        FrontAntiRollBarLabel           matlab.ui.control.Label
        FrontAntiRollBarDropDown        matlab.ui.control.DropDown
        OverrideApplyButtonFrontAntiRollBar  matlab.ui.control.Button
        RearAntiRollBarLabel            matlab.ui.control.Label
        RearAntiRollBarDropDown         matlab.ui.control.DropDown
        OverrideApplyButtonRearAntiRollBar  matlab.ui.control.Button
        BodyOpaqueCheckBox              matlab.ui.control.CheckBox
        CreateDataStructureButton       matlab.ui.control.Button
        OverrideApplyButtonAero         matlab.ui.control.Button
        OverrideApplyButtonBody         matlab.ui.control.Button
        PeopleLabel                     matlab.ui.control.Label
        PeopleFLCheckBox                matlab.ui.control.CheckBox
        PeopleFRCheckBox                matlab.ui.control.CheckBox
        PeopleRLCheckBox                matlab.ui.control.CheckBox
        PeopleRRCheckBox                matlab.ui.control.CheckBox
        PeopleDriverCheckBox            matlab.ui.control.CheckBox
        OverrideApplyButtonPeople       matlab.ui.control.Button
        HelpLabelConfigure              matlab.ui.control.Label
        PowerActiveCoolingCheckBox      matlab.ui.control.CheckBox
        FrontTirePanel                  matlab.ui.container.Panel
        FrontTireModelDropDown          matlab.ui.control.DropDown
        OverrideApplyButtonFrontTires   matlab.ui.control.Button
        TireDynamicsLabel               matlab.ui.control.Label
        TireModelLabel                  matlab.ui.control.Label
        TireSizeLabel                   matlab.ui.control.Label
        TireBodyLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        FrontTireDynamicsDropDown       matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        FrontTireDataDropDown           matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        FrontTireBodyDropDown           matlab.ui.control.DropDown
        SlipLabel                       matlab.ui.control.Label
        FrontTireSlipDropDown           matlab.ui.control.DropDown
        ContactLabel                    matlab.ui.control.Label
        FrontTireContactDropDown        matlab.ui.control.DropDown
        FrontTireLabel                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RearTireModelDropDown           matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        RearTireDataDropDown            matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        RearTireBodyDropDown            matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        RearTireDynamicsDropDown        matlab.ui.control.DropDown
        OverrideApplyButtonRearTires    matlab.ui.control.Button
        SlipLabel_2                     matlab.ui.control.Label
        RearTireSlipDropDown            matlab.ui.control.DropDown
        ContactLabel_2                  matlab.ui.control.Label
        RearTireContactDropDown         matlab.ui.control.DropDown
        RearLabel                       matlab.ui.control.Label
        MatrixTab                       matlab.ui.container.Tab
        ConfigurationsButtonGroup       matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        Button_8                        matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        Button_10                       matlab.ui.control.RadioButton
        Button_11                       matlab.ui.control.RadioButton
        Button_12                       matlab.ui.control.RadioButton
        Button_13                       matlab.ui.control.RadioButton
        Button_14                       matlab.ui.control.RadioButton
        Button_15                       matlab.ui.control.RadioButton
        Button_16                       matlab.ui.control.RadioButton
        Button_17                       matlab.ui.control.RadioButton
        Button_18                       matlab.ui.control.RadioButton
        Button_19                       matlab.ui.control.RadioButton
        Button_20                       matlab.ui.control.RadioButton
        Button_21                       matlab.ui.control.RadioButton
        Button_22                       matlab.ui.control.RadioButton
        Button_23                       matlab.ui.control.RadioButton
        Button_24                       matlab.ui.control.RadioButton
        Button_25                       matlab.ui.control.RadioButton
        Button_26                       matlab.ui.control.RadioButton
        Button_27                       matlab.ui.control.RadioButton
        Button_28                       matlab.ui.control.RadioButton
        Button_29                       matlab.ui.control.RadioButton
        Button_30                       matlab.ui.control.RadioButton
        Button_31                       matlab.ui.control.RadioButton
        Button_32                       matlab.ui.control.RadioButton
        Button_33                       matlab.ui.control.RadioButton
        Button_34                       matlab.ui.control.RadioButton
        Button_35                       matlab.ui.control.RadioButton
        Button_36                       matlab.ui.control.RadioButton
        Button_37                       matlab.ui.control.RadioButton
        Button_38                       matlab.ui.control.RadioButton
        Button_39                       matlab.ui.control.RadioButton
        Button_40                       matlab.ui.control.RadioButton
        Button_41                       matlab.ui.control.RadioButton
        Button_42                       matlab.ui.control.RadioButton
        Button_43                       matlab.ui.control.RadioButton
        Button_44                       matlab.ui.control.RadioButton
        Button_45                       matlab.ui.control.RadioButton
        Button_46                       matlab.ui.control.RadioButton
        Button_47                       matlab.ui.control.RadioButton
        Button_48                       matlab.ui.control.RadioButton
        Button_49                       matlab.ui.control.RadioButton
        Button_50                       matlab.ui.control.RadioButton
        Button_51                       matlab.ui.control.RadioButton
        Button_52                       matlab.ui.control.RadioButton
        Button_53                       matlab.ui.control.RadioButton
        Button_54                       matlab.ui.control.RadioButton
        Button_55                       matlab.ui.control.RadioButton
        Button_56                       matlab.ui.control.RadioButton
        Button_57                       matlab.ui.control.RadioButton
        Button_58                       matlab.ui.control.RadioButton
        Button_59                       matlab.ui.control.RadioButton
        Button_60                       matlab.ui.control.RadioButton
        Button_61                       matlab.ui.control.RadioButton
        Button_62                       matlab.ui.control.RadioButton
        Button_63                       matlab.ui.control.RadioButton
        Button_64                       matlab.ui.control.RadioButton
        Button_65                       matlab.ui.control.RadioButton
        Button_66                       matlab.ui.control.RadioButton
        Button_67                       matlab.ui.control.RadioButton
        Button_68                       matlab.ui.control.RadioButton
        Button_69                       matlab.ui.control.RadioButton
        Button_70                       matlab.ui.control.RadioButton
        Button_71                       matlab.ui.control.RadioButton
        Button_72                       matlab.ui.control.RadioButton
        Button_73                       matlab.ui.control.RadioButton
        Button_74                       matlab.ui.control.RadioButton
        Button_75                       matlab.ui.control.RadioButton
        Button_76                       matlab.ui.control.RadioButton
        Button_77                       matlab.ui.control.RadioButton
        Button_78                       matlab.ui.control.RadioButton
        Button_79                       matlab.ui.control.RadioButton
        Button_80                       matlab.ui.control.RadioButton
        Button_81                       matlab.ui.control.RadioButton
        Button_82                       matlab.ui.control.RadioButton
        Button_83                       matlab.ui.control.RadioButton
        Button_84                       matlab.ui.control.RadioButton
        Button_85                       matlab.ui.control.RadioButton
        Button_86                       matlab.ui.control.RadioButton
        Button_87                       matlab.ui.control.RadioButton
        Button_88                       matlab.ui.control.RadioButton
        Button_89                       matlab.ui.control.RadioButton
        Button_90                       matlab.ui.control.RadioButton
        Button_91                       matlab.ui.control.RadioButton
        Button_92                       matlab.ui.control.RadioButton
        Button_93                       matlab.ui.control.RadioButton
        Button_94                       matlab.ui.control.RadioButton
        Button_95                       matlab.ui.control.RadioButton
        Button_96                       matlab.ui.control.RadioButton
        Button_97                       matlab.ui.control.RadioButton
        Button_98                       matlab.ui.control.RadioButton
        Button_99                       matlab.ui.control.RadioButton
        Button_100                      matlab.ui.control.RadioButton
        Button_101                      matlab.ui.control.RadioButton
        Button_102                      matlab.ui.control.RadioButton
        Button_103                      matlab.ui.control.RadioButton
        Button_104                      matlab.ui.control.RadioButton
        Button_105                      matlab.ui.control.RadioButton
        Button_106                      matlab.ui.control.RadioButton
        Button_107                      matlab.ui.control.RadioButton
        Button_108                      matlab.ui.control.RadioButton
        Button_109                      matlab.ui.control.RadioButton
        Button_110                      matlab.ui.control.RadioButton
        Button_111                      matlab.ui.control.RadioButton
        Button_112                      matlab.ui.control.RadioButton
        Button_113                      matlab.ui.control.RadioButton
        Button_114                      matlab.ui.control.RadioButton
        Button_115                      matlab.ui.control.RadioButton
        Button_116                      matlab.ui.control.RadioButton
        Button_117                      matlab.ui.control.RadioButton
        Button_118                      matlab.ui.control.RadioButton
        Button_119                      matlab.ui.control.RadioButton
        Button_120                      matlab.ui.control.RadioButton
        Button_121                      matlab.ui.control.RadioButton
        Button_122                      matlab.ui.control.RadioButton
        Button_123                      matlab.ui.control.RadioButton
        Button_124                      matlab.ui.control.RadioButton
        Button_125                      matlab.ui.control.RadioButton
        Button_126                      matlab.ui.control.RadioButton
        Button_127                      matlab.ui.control.RadioButton
        Button_128                      matlab.ui.control.RadioButton
        MS1Label                        matlab.ui.control.Label
        MSDLabel                        matlab.ui.control.Label
        MS3Label                        matlab.ui.control.Label
        MSFLabel                        matlab.ui.control.Label
        ML1Label                        matlab.ui.control.Label
        MLDLabel                        matlab.ui.control.Label
        ML3Label                        matlab.ui.control.Label
        MLFLabel                        matlab.ui.control.Label
        SS1Label                        matlab.ui.control.Label
        SSDLabel                        matlab.ui.control.Label
        SS3Label                        matlab.ui.control.Label
        SSFLabel                        matlab.ui.control.Label
        SL1Label                        matlab.ui.control.Label
        SLDLabel                        matlab.ui.control.Label
        SL3Label                        matlab.ui.control.Label
        SLFLabel                        matlab.ui.control.Label
        DWLabel                         matlab.ui.control.Label
        DWaLabel                        matlab.ui.control.Label
        S2LAFLabel                      matlab.ui.control.Label
        S2LARLabel                      matlab.ui.control.Label
        S25LAFLabel                     matlab.ui.control.Label
        S25LARLabel                     matlab.ui.control.Label
        CS52LAFLabel                    matlab.ui.control.Label
        DOFLabel                        matlab.ui.control.Label
        AbbreviationLabels              matlab.ui.control.Label
        HelpLabelMatrix                 matlab.ui.control.Label
        RowabbreviationindicatestireanddrivelineconfigurationLabel  matlab.ui.control.Label
        EventTab                        matlab.ui.container.Tab
        ManeuverLabel                   matlab.ui.control.Label
        ManeuverDropDown                matlab.ui.control.DropDown
        SurfaceDropDownLabel            matlab.ui.control.Label
        SurfaceDropDown                 matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        PlotManeuverButton              matlab.ui.control.Button
        PointLabelIntervalEditFieldLabel  matlab.ui.control.Label
        PointLabelIntervalEditField     matlab.ui.control.NumericEditField
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ShowPaceCarInteractiveCheckBox  matlab.ui.control.CheckBox
        IndividualSettingsLabel         matlab.ui.control.Label
        ManeuverCodeButton              matlab.ui.control.Button
        PlotManeuverCodeButton          matlab.ui.control.Button
        SurfaceCodeButton               matlab.ui.control.Button
        SolverCodeButton                matlab.ui.control.Button
        WindLabel                       matlab.ui.control.Label
        WindCarCheckBox                 matlab.ui.control.CheckBox
        WindTrailerCheckBox             matlab.ui.control.CheckBox
        OverrideWindButton              matlab.ui.control.Button
        WindCodeButton                  matlab.ui.control.Button
        SelectManeuverLabel             matlab.ui.control.Label
        DefineNewTrajectoryButton       matlab.ui.control.Button
        UnrealEngineAnimationCheckBox   matlab.ui.control.CheckBox
        ResultsTab                      matlab.ui.container.Tab
        PositionandSpeedButton          matlab.ui.control.Button
        PlotsLabel                      matlab.ui.control.Label
        WheelSpeedsButton               matlab.ui.control.Button
        ManeuverButton                  matlab.ui.control.Button
        ReplayinUnrealButton            matlab.ui.control.Button
        AnimationLabel                  matlab.ui.control.Label
        PositionandSpeedCodeButton      matlab.ui.control.Button
        WheelSpeedsCodeButton           matlab.ui.control.Button
        ResultsManeuverCodeButton       matlab.ui.control.Button
        BodyMeasurementsButton          matlab.ui.control.Button
        BodyMeasurementsCodeButton      matlab.ui.control.Button
        SimscapeResultsExplorerButton   matlab.ui.control.Button
        SimulationDataInspectorButton   matlab.ui.control.Button
        PowertrainCurrentButton         matlab.ui.control.Button
        PowertrainCurrentCodeButton     matlab.ui.control.Button
        MainTab                         matlab.ui.container.Tab
        RegenerateVehiclePresetsButton  matlab.ui.control.Button
        RegenerateVehicleDatabaseButton  matlab.ui.control.Button
        RegenerateManueverDatabaseButton  matlab.ui.control.Button
        RegenerateDatabasesinMATLABWorkspaceLabel  matlab.ui.control.Label
        RegenerateInitDatabaseButton    matlab.ui.control.Button
        RegenerateDriverDatabaseButton  matlab.ui.control.Button
        RegenerateSceneDatabaseButton   matlab.ui.control.Button
        RegenerateCameraDatabaseButton  matlab.ui.control.Button
        VehicleDatabaseLabel            matlab.ui.control.Label
        VehiclePresetsLabel             matlab.ui.control.Label
        ManeuverDatabaseLabel           matlab.ui.control.Label
        InitDatabaseLabel               matlab.ui.control.Label
        DriverDatabaseLabel             matlab.ui.control.Label
        SceneDatabaseLabel              matlab.ui.control.Label
        CameraDatabaseLabel             matlab.ui.control.Label
        VehiclePresetsFileButton        matlab.ui.control.Button
        ManeuverDatabaseFileButton      matlab.ui.control.Button
        InitDatabaseFileButton          matlab.ui.control.Button
        DriverDatabaseFileButton        matlab.ui.control.Button
        SceneDatabaseFileButton         matlab.ui.control.Button
        CameraDatabaseFileButton        matlab.ui.control.Button
        HelpLabelMain                   matlab.ui.control.Label
        RegenerateAllDatabasesButton    matlab.ui.control.Button
        RegenerateAllDatabasesFileButton  matlab.ui.control.Button
        TestModelsLabel                 matlab.ui.control.Label
        FuelCellVehicle1DButton         matlab.ui.control.Button
        SimscapeVehicleTemplateConfigurationLabel  matlab.ui.control.Label
        ConfigureModelButton            matlab.ui.control.Button
        LoadVDatabase                   matlab.ui.control.Button
        ListVehicleConfigurationButton  matlab.ui.control.Button
        OpenModelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        highlightColor = [180 230 255]/255; % color to highlight button
        defaultColor = [0.96 0.96 0.96]; % default color for button
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: 
        % SelectVehicleConfigurationButtonGroup
        function CustomSelectVehicleConfigurationButtonGroupSelecti(app, event)
            selectedButton = app.SelectVehicleConfigurationButtonGroup.SelectedObject;
            switch lower(selectedButton.Text)
                case 'mfeval tire (hamba)',          preset_ind = '139';
                case 'swift tire (hamba)',           preset_ind = '140';
                case 'live axle mfeval (hamba)',     preset_ind = '158';
                case 'live axle swift (hamba)',      preset_ind = '159';
                case 'emotor 2x mfeval (hamba)',     preset_ind = '160';
                case 'emotor 2x swift (hamba)',      preset_ind = '161';
                case 'emotor 3x mfeval (hamba)',     preset_ind = '162';
                case 'emotor 3x swift (hamba)',      preset_ind = '163';
                case '15dof mfeval (hamba)',         preset_ind = '164';
                case '15dof swift (hamba)',          preset_ind = '165';
                case '15dof swift (hamba) 1d',       preset_ind = '170';
                case '15dof mot2x cool mf (hamba)',  preset_ind = '166';    
                case '15dof mot2x cool swi (hamba)', preset_ind = '167';    
                case '15dof mot3x cool mf (hamba)',  preset_ind = '168';    
                case '15dof mot3x cool swi (hamba)', preset_ind = '169';
                case '15dof fuel cell mf (hamba)',   preset_ind = '174';    
                case '15dof fuel cell swi (hamba)',  preset_ind = '173';    
                case 'real-time config (basic)',     preset_ind = '175';    
                case 'real-time config (full)',      preset_ind = '176';    
                case 'mfeval tire 2xr (makhulu)',    preset_ind = '143'; 
                case 'swift tire 2xr (makhulu)',     preset_ind = '145';
                case 'mfeval tire 1xr (makhulu)',    preset_ind = '144';
                case 'swift tire 1xr (makhulu)',     preset_ind = '146';
                case 'mfeval 2xr fuel cell (mak)',   preset_ind = '178'; 
                case 'swift 2xr fuel cell (mak)',    preset_ind = '177'; 
                case 'default',                      preset_ind = '002'; 
                case 'one power shaft',              preset_ind = '128';
                case 'cvs front and rear',           preset_ind = '129';
                case 'anti-lock brakes',             preset_ind = '130';
                case 'nonlinear springs front',      preset_ind = '131';
                case 'nonlinear springs rear',       preset_ind = '132';
                case 'springs with no variants',     preset_ind = '133';
                case 'nonlinear dampers rear',       preset_ind = '134';
                case 'nonlinear dampers front',      preset_ind = '135';
                case 'cv-cv prismatic front',        preset_ind = '136'; 
                case 'stiff spring damper',          preset_ind = '137';
            end
            str = ['sm_car_load_vehicle_data(''sm_car'',''' preset_ind ''');'];
            disp(str)
            evalin('base',str);
            app.ConfigureModelButton.BackgroundColor = app.defaultColor;
        end

        % Button pushed function: CreateDataStructureButton
        function CreateDataStructureButtonPushed(app, event)
            OverrideApplyButtonAeroPushed(app, event);
            OverrideApplyButtonBodyPushed(app, event);
            OverrideApplyButtonBodyGeometryPushed(app, event);
            OverrideApplyButtonFrontSuspensionPushed(app, event);
            OverrideApplyButtonFrontSteerPushed(app, event);
            OverrideApplyButtonFrontAntiRollBarPushed(app, event);
            OverrideApplyButtonRearSuspensionPushed(app, event);
            OverrideApplyButtonRearSteerPushed(app, event);
            OverrideApplyButtonRearAntiRollBarPushed(app, event);
            OverrideApplyButtonSpringsPushed(app, event);
            OverrideApplyButtonDampersPushed(app, event);
            OverrideApplyButtonFrontTiresPushed(app, event);
            OverrideApplyButtonRearTiresPushed(app, event);
            OverrideApplyButtonPowerPushed(app, event);
            OverrideApplyButtonDrivelinePushed(app, event);
            OverrideApplyButtonBrakesPushed(app, event);
            BodyOpaqueCheckBoxValueChanged(app, event);
            ABSControlCheckBoxValueChanged(app, event);
        end

        % Selection changed function: ConfigurationsButtonGroup
        function MatrixConfigSelectionChanged2(app, event)
            selectedButton = app.ConfigurationsButtonGroup.SelectedObject;
            sm_car_load_vehicle_data('sm_car',selectedButton.Text);
        end

        % Button pushed function: ConfigureModelButton
        function ConfigureModelButtonPushed(app, event)
            str = ['sm_car_config_vehicle(''sm_car'');'];
            disp(str);
            eval(str);
            app.ConfigureModelButton.BackgroundColor = app.defaultColor;
        end

        % Value changed function: ManeuverDropDown
        function ManeuverDropDownValueChanged(app, event)
            value = app.ManeuverDropDown.Value;
            str = ['sm_car_config_maneuver(''sm_car'',''' value ''');'];
            disp(str);
            evalin('base',str);
            if(strcmp(value,'MCity'))
                app.DefineNewTrajectoryButton.Enable = 'on';
            else
                app.DefineNewTrajectoryButton.Enable = 'off';
            end
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SurfaceDropDown
        function SurfaceDropDownValueChanged(app, event)
            value = app.SurfaceDropDown.Value;
            str = ['sm_car_config_road(''sm_car'',''' value ''');'];
            disp(str);
            evalin('base',str);
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            value = app.SolverDropDown.Value;
            str = ['sm_car_config_solver(''sm_car'',''' value ''');'];
            disp(str);
            evalin('base',str);
        end

        % Value changed function: ABSControlCheckBox
        function ABSControlCheckBoxValueChanged(app, event)
            value = app.ABSControlCheckBox.Value;
            str = ['sm_car_config_control_brake(''sm_car'',' num2str(value) ');'];
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotManeuverButton
        function PlotManeuverButtonPushed(app, event)
            ManeuverValue = app.ManeuverDropDown.Value;
            PointLabelIntervalValue = app.PointLabelIntervalEditField.Value;
            sm_car_plot_maneuver(evalin('base','Maneuver'),PointLabelIntervalValue);
        end

        % Value changed function: DriverDropDown
        function DriverDropDownValueChanged(app, event)
            value = app.DriverDropDown.Value;
            disp(['set_param(''sm_car/Driver'',''popup_driver_type'',' value ');'])
            set_param('sm_car/Driver','popup_driver_type',value);
        end

        % Value changed function: ShowPaceCarInteractiveCheckBox
        function ShowPaceCarInteractiveCheckBoxValueChanged(app, event)
            value = app.ShowPaceCarInteractiveCheckBox.Value;
            if(value)
                set_param('sm_car/Driver/Interactive','popup_pace_car','show')
            else
                set_param('sm_car/Driver/Interactive','popup_pace_car','none');
            end
        end

        % Button pushed function: PositionandSpeedButton
        function PositionandSpeedButtonPushed(app, event)
            evalin('base','sm_car_plot1speed');
        end

        % Button pushed function: WheelSpeedsButton
        function WheelSpeedsButtonPushed(app, event)
            evalin('base','sm_car_plot2whlspeed');
        end

        % Button pushed function: ManeuverButton
        function ManeuverButtonPushed(app, event)
            str = 'sm_car_plot3maneuver(Maneuver,logsout_sm_car);';
            disp(str)
            evalin('base',str);
        end

        % Callback function
        function BodyGeometryDropDown_2ValueChanged(app, event)
            value = app.BodyGeometryDropDown_2.Value;
            evalin('base',['Vehicle.Chassis.Body.BodyGeometry = VDatabase.BodyGeometry.' value ';']);
            evalin('base','sm_car_config_vehicle(''sm_car'');');
        end

        % Button pushed function: OverrideApplyButtonFrontSteer
        function OverrideApplyButtonFrontSteerPushed(app, event)
            value = app.FrontSteerDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''front'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''front'');']);
            app.OverrideApplyButtonFrontSteer.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSprings
        function OverrideApplyButtonSpringsPushed(app, event)
            value = app.SpringsDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setSpring(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setSpring(Vehicle,''' value ''');']);
            app.OverrideApplyButtonSprings.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonBodyGeometry
        function OverrideApplyButtonBodyGeometryPushed(app, event)
            value = app.BodyGeometryDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setBodyGeometry(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setBodyGeometry(Vehicle,''' value ''');']);            
            app.OverrideApplyButtonBodyGeometry.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonDampers
        function OverrideApplyButtonDampersPushed(app, event)
            value = app.DampersDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setDamper(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setDamper(Vehicle,''' value ''');']);
            app.OverrideApplyButtonDampers.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonPower
        function OverrideApplyButtonPowerPushed(app, event)
            value = app.PowerDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setPower(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setPower(Vehicle,''' value ''');']);
            PowerCoolingvalue = app.PowerActiveCoolingCheckBox.Value;
            if(PowerCoolingvalue)
                coolingSetting = 'Active_1_Loop';
            else
                coolingSetting = 'None';
            end
            str = (['Vehicle = sm_car_vehcfg_setPowerCooling(Vehicle,''' coolingSetting ''');']);
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonPower.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonDriveline
        function OverrideApplyButtonDrivelinePushed(app, event)
            value = app.DrivelineDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setDrv(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setDrv(Vehicle,''' value ''');']);            
            app.OverrideApplyButtonDriveline.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonFrontSuspension
        function OverrideApplyButtonFrontSuspensionPushed(app, event)
            value = app.FrontSuspensionDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''front'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''front'');']);            
            app.OverrideApplyButtonFrontSuspension.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonRearSuspension
        function OverrideApplyButtonRearSuspensionPushed(app, event)
            value = app.RearSuspensionDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''rear'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''rear'');']);                        
            app.OverrideApplyButtonRearSuspension.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonBrakes
        function OverrideApplyButtonBrakesPushed(app, event)
            value = app.BrakesDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setBrakes(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setBrakes(Vehicle,''' value ''');']);
            ABSControlCheckBoxValueChanged(app, event);
            app.OverrideApplyButtonBrakes.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonRearSteer
        function OverrideApplyButtonRearSteerPushed(app, event)
            value = app.RearSteerDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''rear'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''rear'');']);            
            app.OverrideApplyButtonRearSteer.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonFrontTires
        function OverrideApplyButtonFrontTiresPushed(app, event)
            valueModel = app.FrontTireModelDropDown.Value;
            valueData = app.FrontTireDataDropDown.Value;
            valueBody = app.FrontTireBodyDropDown.Value;
            tire_cfg_str = [valueModel '_' valueBody '_' valueData];
            disp         (['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''front'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''front'');']);            
            value = app.FrontTireDynamicsDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''front'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''front'');']);                        
            value = app.FrontTireSlipDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''front'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''front'');']);
            value = app.FrontTireContactDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''front'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''front'');']);
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonRearTires
        function OverrideApplyButtonRearTiresPushed(app, event)
            valueModel = app.RearTireModelDropDown.Value;
            valueData = app.RearTireDataDropDown.Value;
            valueBody = app.RearTireBodyDropDown.Value;
            tire_cfg_str = [valueModel '_' valueBody '_' valueData];
            disp         (['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''rear'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''rear'');']);            
            value = app.RearTireDynamicsDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''rear'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''rear'');']);                        
            value = app.RearTireSlipDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''rear'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''rear'');']);
            value = app.RearTireContactDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''rear'');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''rear'');']);
            app.OverrideApplyButtonRearTires.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: 
        % OverrideApplyButtonFrontAntiRollBar
        function OverrideApplyButtonFrontAntiRollBarPushed(app, event)
            value = app.FrontAntiRollBarDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAntiRollBar(Vehicle,''' value ''',''front'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonFrontAntiRollBar.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonRearAntiRollBar
        function OverrideApplyButtonRearAntiRollBarPushed(app, event)
            value = app.RearAntiRollBarDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAntiRollBar(Vehicle,''' value ''',''rear'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonRearAntiRollBar.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: LoadVDatabase
        function LoadVDatabaseButtonPushed(app, event)
            str = 'VDatabase = sm_car_import_vehicle_data(''sm_car_database_Vehicle.xlsx'',{''Structure'',''NameConvention''},1,1);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: ListVehicleConfigurationButton
        function ListVehicleConfigurationButtonPushed(app, event)
            str = 'sm_car_vehcfg_getConfig(Vehicle)';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            str = 'open_system(''sm_car'')';
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: ReplayinUnrealButton
        function ReplayinUnrealButtonPushed(app, event)
            if(verLessThan('matlab','9.7'))
                str = 'open_system(''sm_car_Unreal_Engine_replay_results'')';
            else
                str = 'open_system(''sm_car_Unreal_Engine_replay_results_R19b'')';
            end
            disp(str);
            evalin('base',str);
        end

        % Value changed function: BodyOpaqueCheckBox
        function BodyOpaqueCheckBoxValueChanged(app, event)
            value = app.BodyOpaqueCheckBox.Value;
            if(value)
                disp('Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 1;');
                evalin('base','Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 1;');
            else
                disp('Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 0.3;');
                evalin('base','Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 0.3;');
            end
        end

        % Button pushed function: RegenerateVehiclePresetsButton
        function RegenerateVehiclePresetsButtonPushed(app, event)
            str = 'sm_car_vehicle_data_assemble_set';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateVehicleDatabaseButton
        function RegenerateVehicleDatabaseButtonPushed(app, event)
            str='VDatabase = sm_car_import_vehicle_data(''sm_car_database_Vehicle.xlsx'',{''Structure'',''NameConvention''},0,1);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateManueverDatabaseButton
        function RegenerateManueverDatabaseButtonPushed(app, event)
            str = 'sm_car_gen_upd_database(''Maneuver'',0);';
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: RegenerateInitDatabaseButton
        function RegenerateInitDatabaseButtonPushed(app, event)
            str = 'sm_car_gen_upd_database(''Init'',0);';
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: RegenerateDriverDatabaseButton
        function RegenerateDriverDatabaseButtonPushed(app, event)
            str = 'sm_car_gen_upd_database(''Driver'',0);';
            disp(str)
            evalin('base',str);            
        end

        % Button pushed function: RegenerateCameraDatabaseButton
        function RegenerateCameraDatabaseButtonPushed(app, event)
            str = 'sm_car_gen_upd_database(''Camera'',0);';
            disp(str)
            evalin('base',str);            
        end

        % Button pushed function: VehiclePresetsFileButton
        function VehiclePresetsFileButtonPushed(app, event)
            edit sm_car_vehicle_data_assemble_set.m
        end

        % Button pushed function: ManeuverDatabaseFileButton
        function ManeuverDatabaseFileButtonPushed(app, event)
            sm_car_winopen_file('sm_car_database_Maneuver.xlsx');
        end

        % Button pushed function: InitDatabaseFileButton
        function InitDatabaseFileButtonPushed(app, event)
            sm_car_winopen_file('sm_car_database_Init.xlsx');
        end

        % Button pushed function: DriverDatabaseFileButton
        function DriverDatabaseFileButtonPushed(app, event)
            sm_car_winopen_file('sm_car_database_Driver.xlsx');
        end

        % Button pushed function: SceneDatabaseFileButton
        function SceneDatabaseFileButtonPushed(app, event)
            edit sm_car_import_scene_data.m
        end

        % Button pushed function: CameraDatabaseFileButton
        function CameraDatabaseFileButtonPushed(app, event)
            sm_car_winopen_file('sm_car_database_Camera.xlsx');
        end

        % Button pushed function: RegenerateSceneDatabaseButton
        function RegenerateSceneDatabaseButtonPushed(app, event)
            str = 'Scene = sm_car_import_scene_data;';
            evalin('base',str)
        end

        % Button pushed function: OverrideApplyButtonAero
        function OverrideApplyButtonAeroPushed(app, event)
            value = app.AeroDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAero(Vehicle,''' value ''');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonAero.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonBody
        function OverrideApplyButtonBodyPushed(app, event)
            value = app.BodyDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setBody(Vehicle,''' value ''');'];
            disp(str);
            evalin('base',str);
            OverrideApplyButtonBodyGeometryPushed(app, event)
            app.OverrideApplyButtonBody.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: ManeuverCodeButton
        function ManeuverCodeButtonPushed(app, event)
            edit sm_car_config_maneuver.m
        end

        % Button pushed function: PlotManeuverCodeButton
        function PlotManeuverCodeButtonPushed(app, event)
            edit sm_car_plot_maneuver.m
        end

        % Button pushed function: SurfaceCodeButton
        function SurfaceCodeButtonPushed(app, event)
            edit sm_car_config_road.m
        end

        % Button pushed function: SolverCodeButton
        function SolverCodeButtonPushed(app, event)
            edit sm_car_config_solver.m
        end

        % Button pushed function: OverrideApplyButtonPeople
        function OverrideApplyButtonPeoplePushed(app, event)
            PeopleDrivervalue = app.PeopleDriverCheckBox.Value;
            PeopleFLvalue = app.PeopleFLCheckBox.Value;
            PeopleFRvalue = app.PeopleFRCheckBox.Value;
            PeopleRLvalue = app.PeopleRLCheckBox.Value;
            PeopleRRvalue = app.PeopleRRCheckBox.Value;
            str = ['Vehicle = sm_car_vehcfg_setPeopleOnOff(Vehicle,[' num2str(PeopleDrivervalue) ' ' num2str(PeopleFLvalue) ' ' num2str(PeopleFRvalue) ' ' num2str(PeopleRLvalue) ' ' num2str(PeopleRRvalue) ']);'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonPeople.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Selection changed function: 
        % SelectTrailerConfigurationButtonGroup
        function SelectTrailerConfigurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectTrailerConfigurationButtonGroup.SelectedObject;
            switch lower(selectedButton.Text)
                case 'no trailer',              preset_ind = 'None';
                case 'elula (mfeval)',          preset_ind = '01';
                case 'thwala (mfeval)',         preset_ind = '02';
                case 'elula (swift)',           preset_ind = '03';
                case 'thwala (swift)',          preset_ind = '04';
                case 'elula (mfeval unstable)', preset_ind = '05';
            end
            str = ['sm_car_load_trailer_data(''sm_car'',''' preset_ind ''');'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: OverrideWindButton
        function OverrideWindButtonPushed(app, event)
            WindCarvalue = app.WindCarCheckBox.Value;
            WindTrlvalue = app.WindTrailerCheckBox.Value;
            str = ['sm_car_config_wind(''sm_car'',' num2str(WindCarvalue) ',' num2str(WindTrlvalue) ');'];
            disp(str);
            evalin('base',str); 
        end

        % Button pushed function: WindCodeButton
        function WindCodeButtonPushed(app, event)
            edit('sm_car_config_wind.m');
        end

        % Button pushed function: PositionandSpeedCodeButton
        function PositionandSpeedCodeButtonPushed(app, event)
            edit('sm_car_plot1speed.m');
        end

        % Button pushed function: WheelSpeedsCodeButton
        function WheelSpeedsCodeButtonPushed(app, event)
            edit('sm_car_plot2whlspeed.m');
        end

        % Button pushed function: ResultsManeuverCodeButton
        function ResultsManeuverCodeButtonPushed(app, event)
            edit('sm_car_plot3maneuver.m');
        end

        % Button pushed function: BodyMeasurementsButton
        function BodyMeasurementsButtonPushed(app, event)
            str = 'sm_car_plot5bodymeas';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: BodyMeasurementsCodeButton
        function BodyMeasurementsCodeButtonPushed(app, event)
            edit('sm_car_plot5bodymeas');
        end

        % Button pushed function: SimscapeResultsExplorerButton
        function SimscapeResultsExplorerButtonPushed(app, event)
            res_vars = evalin('base','who(''simlog_sm_car'');');
            if contains(res_vars,'simlog_sm_car')
                str = 'sscexplore(simlog_sm_car);';
                disp(str);
                evalin('base',str);
            else
                disp('Variable ''simlog_sm_car'' not in base workspace');
            end
        end

        % Button pushed function: RegenerateAllDatabasesButton
        function RegenerateAllDatabasesButtonPushed(app, event)
            str = 'sm_car_gen_all_database';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateAllDatabasesFileButton
        function RegenerateAllDatabasesFileButtonPushed(app, event)
            edit('sm_car_gen_all_database.m');
        end

        % Button pushed function: SimulationDataInspectorButton
        function SimulationDataInspectorButtonPushed(app, event)
            str = 'Simulink.sdi.view';
            disp(str);
            evalin('base',str);
        end

        % Value changed function: PowerDropDown
        function PowerDropDownValueChanged(app, event)
            app.OverrideApplyButtonPower.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PowerActiveCoolingCheckBox
        function PowerActiveCoolingCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPower.BackgroundColor = app.highlightColor;
        end

        % Value changed function: AeroDropDown
        function AeroDropDownValueChanged(app, event)
            app.OverrideApplyButtonAero.BackgroundColor = app.highlightColor;
        end

        % Value changed function: BodyDropDown
        function BodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonBody.BackgroundColor = app.highlightColor;
        end

        % Value changed function: BodyGeometryDropDown
        function BodyGeometryDropDownValueChanged(app, event)
            app.OverrideApplyButtonBodyGeometry.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleDriverCheckBox
        function PeopleDriverCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleFLCheckBox
        function PeopleFLCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleFRCheckBox
        function PeopleFRCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleRLCheckBox
        function PeopleRLCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleRRCheckBox
        function PeopleRRCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: DrivelineDropDown
        function DrivelineDropDownValueChanged(app, event)
            app.OverrideApplyButtonDriveline.BackgroundColor = app.highlightColor;
        end

        % Value changed function: BrakesDropDown
        function BrakesDropDownValueChanged(app, event)
            app.OverrideApplyButtonBrakes.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SpringsDropDown
        function SpringsDropDownValueChanged(app, event)
            app.OverrideApplyButtonSprings.BackgroundColor = app.highlightColor;
        end

        % Value changed function: DampersDropDown
        function DampersDropDownValueChanged(app, event)
            app.OverrideApplyButtonDampers.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontSuspensionDropDown
        function FrontSuspensionDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontSuspension.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearSuspensionDropDown
        function RearSuspensionDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearSuspension.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontSteerDropDown
        function FrontSteerDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontSteer.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearSteerDropDown
        function RearSteerDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearSteer.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontAntiRollBarDropDown
        function FrontAntiRollBarDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontAntiRollBar.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearAntiRollBarDropDown
        function RearAntiRollBarDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearAntiRollBar.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontTireModelDropDown
        function FrontTireModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontTireDataDropDown
        function FrontTireDataDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontTireBodyDropDown
        function FrontTireBodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontTireDynamicsDropDown
        function FrontTireDynamicsDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearTireModelDropDown
        function RearTireModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearTireDataDropDown
        function RearTireDataDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearTireBodyDropDown
        function RearTireBodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearTireDynamicsDropDown
        function RearTireDynamicsDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearTireSlipDropDown
        function RearTireSlipDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: RearTireContactDropDown
        function RearTireContactDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontTireSlipDropDown
        function FrontTireSlipDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: FrontTireContactDropDown
        function FrontTireContactDropDownValueChanged(app, event)
            app.OverrideApplyButtonFrontTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: UnrealEngineAnimationCheckBox
        function UnrealEngineAnimationCheckBoxValueChanged(app, event)
            value = app.UnrealEngineAnimationCheckBox.Value;
            if(value)
                set_param('sm_car/Visualization','unreal_onoff','On');
            else
                set_param('sm_car/Visualization','unreal_onoff','Off');
            end
        end

        % Button pushed function: DefineNewTrajectoryButton
        function DefineNewTrajectoryButtonPushed(app, event)
            edit sm_car_define_maneuver_mcity
        end

        % Button pushed function: FuelCellVehicle1DButton
        function FuelCellVehicle1DButtonPushed(app, event)
            str = 'open_system(''ssc_car_FuelCell1Motor'');';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PowertrainCurrentButton
        function PowertrainCurrentButtonPushed(app, event)
            str = 'sm_car_plot7power(logsout_sm_car);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PowertrainCurrentCodeButton
        function PowertrainCurrentCodeButtonPushed(app, event)
            edit sm_car_plot7power
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PresetsTab = uitab(app.TabGroup);
            app.PresetsTab.Title = 'Presets';
            app.SelectVehicleConfigurationButtonGroup = uibuttongroup(app.PresetsTab);
            app.SelectVehicleConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CustomSelectVehicleConfigurationButtonGroupSelecti, true);
            app.SelectVehicleConfigurationButtonGroup.Title = 'Select Vehicle Configuration';
            app.OnePowerShaftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.OnePowerShaftButton.Text = 'One Power Shaft';
            app.CVsFrontandRearButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.CVsFrontandRearButton.Text = 'CVs Front and Rear';
            app.AntiLockBrakesButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.AntiLockBrakesButton.Text = 'Anti-Lock Brakes';
            app.NonlinearSpringsFrontButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearSpringsFrontButton.Text = 'Nonlinear Springs Front';
            app.NonlinearSpringsRearButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearSpringsRearButton.Text = 'Nonlinear Springs Rear';
            app.SpringswithNoVariantsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SpringswithNoVariantsButton.Text = 'Springs with No Variants';
            app.NonlinearDampersRearButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearDampersRearButton.Text = 'Nonlinear Dampers Rear';
            app.NonlinearDampersFrontButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearDampersFrontButton.Text = 'Nonlinear Dampers Front';
            app.CVCVPrismaticFrontButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.CVCVPrismaticFrontButton.Text = 'CV-CV Prismatic Front';
            app.DefaultButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.StiffSpringDamperButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.StiffSpringDamperButton.Text = 'Stiff Spring Damper';
            app.MFevalTireHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MFevalTireHambaButton.Text = 'MFeval Tire (Hamba)';
            app.MFevalTireHambaButton.Value = true;
            app.SwiftTireHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SwiftTireHambaButton.Text = 'Swift Tire (Hamba)';
            app.SedanHambaLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SedanHambaLabel.Text = 'Sedan Hamba';
            app.SedanHambaLGLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SedanHambaLGLabel.Text = 'Sedan HambaLG';
            app.BusMakhuluLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.BusMakhuluLabel.Text = 'Bus Makhulu';
            app.BusMakhuluSwiftTireButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.BusMakhuluSwiftTireButton.Tooltip = {'Bus with 4 tires on real axle.  Uses MF-Swift tire model.'};
            app.BusMakhuluSwiftTireButton.Text = 'Swift Tire 2xR (Makhulu)';
            app.MFevalTire2xRMakhuluButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MFevalTire2xRMakhuluButton.Tooltip = {'Bus with 4 tires on real axle.  Uses MFeval tire model.'};
            app.MFevalTire2xRMakhuluButton.Text = 'MFeval Tire 2xR (Makhulu)';
            app.MFevalTire1xRMakhuluButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MFevalTire1xRMakhuluButton.Tooltip = {'Bus with 2 tires on real axle.  Uses MFeval tire model.'};
            app.MFevalTire1xRMakhuluButton.Text = 'MFeval Tire 1xR (Makhulu)';
            app.SwiftTyre1xRMakhuluButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SwiftTyre1xRMakhuluButton.Tooltip = {'Bus with 2 tires on real axle.  Uses MF-Swift tire model.'};
            app.SwiftTyre1xRMakhuluButton.Text = 'Swift Tire 1xR (Makhulu)';
            app.LiveAxleMFevalHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.LiveAxleMFevalHambaButton.Tooltip = {'Live axle with leaf springs on rear axle.  MFeval tire model.'};
            app.LiveAxleMFevalHambaButton.Text = 'Live Axle MFeval (Hamba)';
            app.LiveAxleSwiftHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.LiveAxleSwiftHambaButton.Tooltip = {'Live axle with leaf springs on rear axle.  MF-Swift tire model.'};
            app.LiveAxleSwiftHambaButton.Text = 'Live Axle Swift (Hamba)';
            app.EMotor2xMFevalHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.EMotor2xMFevalHambaButton.Tooltip = {'Two electric motors (one front one rear) with MFeval tire model.'};
            app.EMotor2xMFevalHambaButton.Text = 'EMotor 2x MFeval (Hamba)';
            app.EMotor2xSwiftHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.EMotor2xSwiftHambaButton.Tooltip = {'Two electric motors (one front one rear) with MF-Swift tire model.'};
            app.EMotor2xSwiftHambaButton.Text = 'EMotor 2x Swift (Hamba)';
            app.EMotor3xMFevalHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.EMotor3xMFevalHambaButton.Tooltip = {'Three electric motors (one front two rear) with MFeval tire model.'};
            app.EMotor3xMFevalHambaButton.Text = 'EMotor 3x MFeval (Hamba)';
            app.EMotor3xSwiftHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.EMotor3xSwiftHambaButton.Tooltip = {'Three electric motors (one front two rear) with MF-Swift tire model.'};
            app.EMotor3xSwiftHambaButton.Text = 'EMotor 3x Swift (Hamba)';
            app.DOFMFevalHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFMFevalHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  MFeval tire model.'};
            app.DOFMFevalHambaButton.Text = '15DOF MFeval (Hamba)';
            app.DOFSwiftHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFSwiftHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  MF-Swift tire model.'};
            app.DOFSwiftHambaButton.Text = '15DOF Swift (Hamba)';
            app.DOFMot2xCoolSwiHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFMot2xCoolSwiHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  3 electric motors with cooling system. MF-Swift tire model.'};
            app.DOFMot2xCoolSwiHambaButton.Text = '15DOF Mot2x Cool Swi (Hamba)';
            app.DOFMot2xCoolMFHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFMot2xCoolMFHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  3 electric motors with cooling system. MFeval tire model.'};
            app.DOFMot2xCoolMFHambaButton.Text = '15DOF Mot2x Cool MF (Hamba)';
            app.DOFMot3xCoolMFHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFMot3xCoolMFHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  3 electric motors with cooling system. MFeval tire model.'};
            app.DOFMot3xCoolMFHambaButton.Text = '15DOF Mot3x Cool MF (Hamba)';
            app.DOFMot3xCoolSwiHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFMot3xCoolSwiHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  3 electric motors with cooling system. MF-Swift tire model.'};
            app.DOFMot3xCoolSwiHambaButton.Text = '15DOF Mot3x Cool Swi (Hamba)';
            app.DOFSwiftHamba1DButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFSwiftHamba1DButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  MF-Swift tire model.'};
            app.DOFSwiftHamba1DButton.Text = '15DOF Swift (Hamba) 1D';
            app.DOFFuelCellSwiHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFFuelCellSwiHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  1 electric motor powered by battery and fuel cell with cooling system. MF-Swift tire model.'};
            app.DOFFuelCellSwiHambaButton.Text = '15DOF Fuel Cell Swi (Hamba)';
            app.DOFFuelCellMFHambaButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DOFFuelCellMFHambaButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  1 electric motor powered by battery and fuel cell with cooling system. MFeval tire model.'};
            app.DOFFuelCellMFHambaButton.Text = '15DOF Fuel Cell MF (Hamba)';
            app.RealTimeConfigBasicButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.RealTimeConfigBasicButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  Linear transient dynamics on MFeval tire model (better for fixed-step simulation).'};
            app.RealTimeConfigBasicButton.Text = 'Real-Time Config (Basic)';
            app.RealTimeConfigFullButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.RealTimeConfigFullButton.Tooltip = {'Double-wishbone suspension on front and rear axles.  Linear transient dynamics on MFeval tire model (better for fixed-step simulation).'};
            app.RealTimeConfigFullButton.Text = 'Real-Time Config (Full)';
            app.MFeval2xRFuelCellMakButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MFeval2xRFuelCellMakButton.Tooltip = {'Bus with 4 tires on real axle.  Uses MFeval tire model. Fuel cell powertrain.'};
            app.MFeval2xRFuelCellMakButton.Text = 'MFeval 2xR Fuel Cell (Mak)';
            app.Swift2xRFuelCellMakButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Swift2xRFuelCellMakButton.Tooltip = {'Bus with 4 tires on real axle.  Uses MF-Swift tire model. . Fuel cell powertrain.'};
            app.Swift2xRFuelCellMakButton.Text = 'Swift 2xR Fuel Cell (Mak)';
            app.SelectTrailerConfigurationButtonGroup = uibuttongroup(app.PresetsTab);
            app.SelectTrailerConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTrailerConfigurationButtonGroupSelectionChanged, true);
            app.SelectTrailerConfigurationButtonGroup.Title = 'Select Trailer Configuration';
            app.NoTrailerButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.NoTrailerButton.Text = 'No Trailer';
            app.NoTrailerButton.Value = true;
            app.ElulaMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaMFevalButton.Tooltip = {'Generic trailer with MFeval tire model.'};
            app.ElulaMFevalButton.Text = 'Elula (MFeval)';
            app.ThwalaMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ThwalaMFevalButton.Tooltip = {'Specific trailer from CAD with MFeval tire model.'};
            app.ThwalaMFevalButton.Text = 'Thwala (MFeval)';
            app.ElulaSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaSwiftButton.Tooltip = {'Generic trailer with MF-Swift tire model.'};
            app.ElulaSwiftButton.Text = 'Elula (Swift)';
            app.ThwalaSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ThwalaSwiftButton.Tooltip = {'Specific trailer from CAD with MF-Swift tire model.'};
            app.ThwalaSwiftButton.Text = 'Thwala (Swift)';
            app.ElulaMFevalUnstableButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaMFevalUnstableButton.Tooltip = {'Generic trailer with MFeval tire model.  CG of trailers is behind axle which is unstable at high speeds.'};
            app.ElulaMFevalUnstableButton.Text = 'Elula (MFeval Unstable)';
            app.HelpLabelPresets = uilabel(app.PresetsTab);
            app.HelpLabelPresets.Tooltip = {'Selecting a preset below updates the Vehicle or Trailer data structure in the MATLAB workspace and triggers variant selection in the model.  (Version 1.6)'};
            app.HelpLabelPresets.Text = '(?)';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.AeroDropDownLabel = uilabel(app.ConfigureTab);
            app.AeroDropDownLabel.Text = 'Aero';
            app.AeroDropDown = uidropdown(app.ConfigureTab);
            app.AeroDropDown.Items = {'Sedan Hamba', 'Sedan Hamba LG', 'Bus Makhulu'};
            app.AeroDropDown.ItemsData = {'Sedan_Hamba', 'Sedan_HambaLG', 'Bus_Makhulu'};
            app.AeroDropDown.ValueChangedFcn = createCallbackFcn(app, @AeroDropDownValueChanged, true);
            app.AeroDropDown.Tooltip = {'Select option and press Apply button on same row to modify data in workspace.'};
            app.AeroDropDown.Value = 'Sedan_Hamba';
            app.BodyDropDownLabel = uilabel(app.ConfigureTab);
            app.BodyDropDownLabel.Text = 'Body';
            app.BodyDropDown = uidropdown(app.ConfigureTab);
            app.BodyDropDown.Items = {'Sedan Hamba', 'Sedan HambaLG', 'Bus Makhulu'};
            app.BodyDropDown.ItemsData = {'Sedan_Hamba', 'Sedan_HambaLG', 'Bus_Makhulu'};
            app.BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyDropDownValueChanged, true);
            app.BodyDropDown.Value = 'Sedan_Hamba';
            app.PowerDropDownLabel = uilabel(app.ConfigureTab);
            app.PowerDropDownLabel.Text = 'Power';
            app.PowerDropDown = uidropdown(app.ConfigureTab);
            app.PowerDropDown.Items = {'Ideal 1 Motor', 'Ideal 2 Motor', 'Electric 2 Motor', 'Electric 3 Motor', 'None'};
            app.PowerDropDown.ItemsData = {'Ideal1Motor_default', 'Ideal2Motor_default', 'Electric2Motor_default', 'Electric3Motor_default', 'None', ''};
            app.PowerDropDown.ValueChangedFcn = createCallbackFcn(app, @PowerDropDownValueChanged, true);
            app.PowerDropDown.Value = 'Ideal1Motor_default';
            app.BrakesDropDownLabel = uilabel(app.ConfigureTab);
            app.BrakesDropDownLabel.Text = 'Brakes';
            app.BrakesDropDown = uidropdown(app.ConfigureTab);
            app.BrakesDropDown.Items = {'Pedal (Hamba)', 'Pedal (HambaLG)', 'ABS (Hamba)', 'ABS (HambaLG)', 'Pedal (Makhulu)'};
            app.BrakesDropDown.ItemsData = {'Pedal_Sedan_Hamba', 'Pedal_Sedan_HambaLG', 'ABS_Sedan_Hamba', 'ABS_Sedan_HambaLG', 'Pedal_Bus_Makhulu'};
            app.BrakesDropDown.ValueChangedFcn = createCallbackFcn(app, @BrakesDropDownValueChanged, true);
            app.BrakesDropDown.Value = 'Pedal_Sedan_Hamba';
            app.SpringsDropDownLabel = uilabel(app.ConfigureTab);
            app.SpringsDropDownLabel.Text = 'Springs';
            app.SpringsDropDown = uidropdown(app.ConfigureTab);
            app.SpringsDropDown.Items = {'Linear Front | Linear Rear (Hamba)', 'Linear Front | Linear Rear', 'Stiff Front | Stiff Rear', 'Nonlinear Front | Linear Rear', 'Linear Front | Nonlinear Rear | Interconnected', 'Linear Only', 'Linear Front | Linear Rear (Makhulu)'};
            app.SpringsDropDown.ItemsData = {'in_linFlinR_SH', 'in_linFlinR_SHL', 'in_linStiffFlinStiffR_SHL', 'in_nlFlinR_SHL', 'con_linFnlR_SHL', 'lin_linFlinR_SHL', 'in_linFlinR_BM'};
            app.SpringsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpringsDropDownValueChanged, true);
            app.SpringsDropDown.Value = 'in_linFlinR_SH';
            app.DampersDropDownLabel = uilabel(app.ConfigureTab);
            app.DampersDropDownLabel.Text = 'Dampers';
            app.DampersDropDown = uidropdown(app.ConfigureTab);
            app.DampersDropDown.Items = {'Linear Front | Linear Rear (Hamba)', 'Linear Front | Linear Rear', 'Stiff Front | Stiff Rear', 'Linear Front | Nonlinear Rear', 'Nonlinear Front | Linear Rear | Interconnected', 'Linear Front | Linear Rear (Makhulu)'};
            app.DampersDropDown.ItemsData = {'in_linFlinR_SH', 'in_linFlinR_SHL', 'in_linStiffFlinStiffR_SHL', 'in_linFnlR_SHL', 'con_nlFlinR_SHL', 'in_linFlinR_BM'};
            app.DampersDropDown.ValueChangedFcn = createCallbackFcn(app, @DampersDropDownValueChanged, true);
            app.DampersDropDown.Value = 'in_linFlinR_SH';
            app.FrontSuspensionDropDownLabel = uilabel(app.ConfigureTab);
            app.FrontSuspensionDropDownLabel.Text = 'Front Suspension';
            app.FrontSuspensionDropDown = uidropdown(app.ConfigureTab);
            app.FrontSuspensionDropDown.Items = {'Double Wishbone (Hamba)', 'Double Wishbone', 'Double Wishbone (A)', 'Split Lower Arm Shock to Front', 'Split Lower Arm Shock to Rear', 'Five Link Shock to Front', 'Five Link Shock to Rear', 'Five Link Shock to Front (Constraints)', 'Simple 15DOF (Hamba LG)', 'Simple 15DOF (Hamba)', 'Double Wishbone (Makhulu)'};
            app.FrontSuspensionDropDown.ItemsData = {'dwb_SH', 'dwb_SHL', 'dwa_SHL', 'S2LAF_SHL', 'S2LAR_SHL', '5S2LAF_SHL', '5S2LAR_SHL', '5CS2LAF_SHL', '15DOF_SHL', '15DOF_SH'};
            app.FrontSuspensionDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontSuspensionDropDownValueChanged, true);
            app.FrontSuspensionDropDown.Value = 'dwb_SHL';
            app.RearSuspensionDropDownLabel = uilabel(app.ConfigureTab);
            app.RearSuspensionDropDownLabel.Text = 'Rear Suspension';
            app.RearSuspensionDropDown = uidropdown(app.ConfigureTab);
            app.RearSuspensionDropDown.Items = {'Double Wishbone (A) (Hamba)', 'Double Wishbone (A)', 'Split Lower Arm Shock to Front', 'Split Lower Arm Shock to Rear', 'Five Link Shock to Front', 'Five Link Shock to Rear', 'Five Link Shock to Front (Constraints)', 'Simple 15DOF', 'Simple 15DOF (Hamba)', 'Double Wishbone (A) (Makhulu)'};
            app.RearSuspensionDropDown.ItemsData = {'dwa_SH', 'dwb_SHL', 'dwa_SHL', 'S2LAF_SHL', 'S2LAR_SHL', '5S2LAF_SHL', '5S2LAR_SHL', '5CS2LAF_SHL', '15DOF_SHL', 'dwa_BM'};
            app.RearSuspensionDropDown.ValueChangedFcn = createCallbackFcn(app, @RearSuspensionDropDownValueChanged, true);
            app.RearSuspensionDropDown.Value = 'dwa_SH';
            app.FrontSteerDropDownLabel = uilabel(app.ConfigureTab);
            app.FrontSteerDropDownLabel.Text = 'Front Steer';
            app.FrontSteerDropDown = uidropdown(app.ConfigureTab);
            app.FrontSteerDropDown.Items = {'Rack Wheel (Table) (Hamba)', 'Rack (Table)', 'Rack Wheel (Table)', 'Rack Static Shafts (Table)', 'Rack Driven Shafts (Table)', 'Wheel Motion', 'None', 'Rack Wheel (Table) (Makhulu)', 'Ackermann (HambaLG)', 'Ackermann (Hamba)'};
            app.FrontSteerDropDown.ItemsData = {'RackWheel_Sedan_Hamba_f', 'Rack_Sedan_HambaLG_f', 'RackWheel_Sedan_HambaLG_f', 'RackStaticShafts_Sedan_HambaLG_f', 'RackDrivenShafts_Sedan_HambaLG_f', 'WheelDrivenRack_Sedan_HambaLG_f', 'None_default', 'RackWheel_Bus_Makhulu_f', 'Ackermann_HambaLG_f', 'Ackermann_Hamba_f'};
            app.FrontSteerDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontSteerDropDownValueChanged, true);
            app.FrontSteerDropDown.Value = 'RackWheel_Sedan_Hamba_f';
            app.RearSteerDropDownLabel = uilabel(app.ConfigureTab);
            app.RearSteerDropDownLabel.Text = 'Rear Steer';
            app.RearSteerDropDown = uidropdown(app.ConfigureTab);
            app.RearSteerDropDown.Items = {'Rack', 'None'};
            app.RearSteerDropDown.ItemsData = {'Rack_Sedan_HambaLG_r', 'None_default'};
            app.RearSteerDropDown.ValueChangedFcn = createCallbackFcn(app, @RearSteerDropDownValueChanged, true);
            app.RearSteerDropDown.Value = 'None_default';
            app.DrivelineDropDownLabel = uilabel(app.ConfigureTab);
            app.DrivelineDropDownLabel.Text = 'Driveline';
            app.DrivelineDropDown = uidropdown(app.ConfigureTab);
            app.DrivelineDropDown.Items = {'Front 3D CVpCV |  Rear 1D (Hamba)', 'Front 1D              | Rear 1D', 'Front 1D3D          | Rear 1D', 'Front 3D CVpCV |  Rear 1D', 'Front 3D CVpCV flex |  Rear 1D', 'Front 3D CVCVp |  Rear 1D', 'Front 3D CVpCV |  Rear 1D | 1 Shaft', 'Front 3D CVpCV |  Rear 1D | 3 Shaft', 'Front 3D CVpCV |  Rear 3D CVpCV', 'Front 3D CVpCV |  Rear 1D (Makhulu)', ''};
            app.DrivelineDropDown.ItemsData = {'f1D3Dr1D_SH', 'f1Dr1D_SHL', 'f1D3Dr1D_SHL', 'fCVpCVr1D_SHL', 'fCVpCVflexr1D_SHL', 'fCVCVpr1D_SHL', 'fCVpCVr1D_1sh_SHL', 'fCVpCVr1D_3sh_SH', 'fCVpCVrCVpCV_SHL', 'f1D3Dr1D_BM'};
            app.DrivelineDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivelineDropDownValueChanged, true);
            app.DrivelineDropDown.Value = 'f1D3Dr1D_SH';
            app.DefineCustomConfigurationLabel = uilabel(app.ConfigureTab);
            app.DefineCustomConfigurationLabel.Text = 'Define Custom Configuration';
            app.ABSControlCheckBox = uicheckbox(app.ConfigureTab);
            app.ABSControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @ABSControlCheckBoxValueChanged, true);
            app.ABSControlCheckBox.Tooltip = {'Enable ABS controller (valve level control per wheel)'};
            app.ABSControlCheckBox.Text = 'ABS Control';
            app.BodyGeometryDropDownLabel = uilabel(app.ConfigureTab);
            app.BodyGeometryDropDownLabel.Text = 'Body Geometry';
            app.BodyGeometryDropDown = uidropdown(app.ConfigureTab);
            app.BodyGeometryDropDown.Items = {'Sedan Hamba', 'Sedan HambaLG', 'Sedan Scalable', 'Bus Makhulu'};
            app.BodyGeometryDropDown.ItemsData = {'CAD_Sedan_Hamba', 'CAD_Sedan_HambaLG', 'Sedan_Scalable', 'CAD_Bus_Makhulu'};
            app.BodyGeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyGeometryDropDownValueChanged, true);
            app.BodyGeometryDropDown.Value = 'CAD_Sedan_Hamba';
            app.OverrideinWorkspaceLabel = uilabel(app.ConfigureTab);
            app.OverrideinWorkspaceLabel.Text = {'Override in'; 'Workspace'};
            app.OverrideApplyButtonSprings = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSprings.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSpringsPushed, true);
            app.OverrideApplyButtonSprings.Tooltip = {'Overwrite fields within Spring portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSprings.Text = 'Apply';
            app.OverrideApplyButtonFrontSteer = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonFrontSteer.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonFrontSteerPushed, true);
            app.OverrideApplyButtonFrontSteer.Tooltip = {'Overwrite SuspF.Steer portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonFrontSteer.Text = 'Apply';
            app.OverrideApplyButtonBodyGeometry = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonBodyGeometry.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonBodyGeometryPushed, true);
            app.OverrideApplyButtonBodyGeometry.Tooltip = {'Overwrite BodyGeometry portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonBodyGeometry.Text = 'Apply';
            app.OverrideApplyButtonDampers = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonDampers.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonDampersPushed, true);
            app.OverrideApplyButtonDampers.Tooltip = {'Overwrite fields within Damper portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonDampers.Text = 'Apply';
            app.OverrideApplyButtonPower = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonPower.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonPowerPushed, true);
            app.OverrideApplyButtonPower.Tooltip = {'Overwrite Power and Power.Cooling portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonPower.Text = 'Apply';
            app.OverrideApplyButtonDriveline = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonDriveline.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonDrivelinePushed, true);
            app.OverrideApplyButtonDriveline.Tooltip = {'Overwrite several fields within Driveline portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonDriveline.Text = 'Apply';
            app.OverrideApplyButtonFrontSuspension = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonFrontSuspension.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonFrontSuspensionPushed, true);
            app.OverrideApplyButtonFrontSuspension.Tooltip = {'Overwrite SuspF.Linkage portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonFrontSuspension.Text = 'Apply';
            app.OverrideApplyButtonRearSuspension = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonRearSuspension.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonRearSuspensionPushed, true);
            app.OverrideApplyButtonRearSuspension.Tooltip = {'Overwrite SuspR.Linkage portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonRearSuspension.Text = 'Apply';
            app.OverrideApplyButtonBrakes = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonBrakes.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonBrakesPushed, true);
            app.OverrideApplyButtonBrakes.Tooltip = {'Overwrite Brakes field in Vehicle data structure to match selection. Updates selected brake control to match selection.'};
            app.OverrideApplyButtonBrakes.Text = 'Apply';
            app.OverrideApplyButtonRearSteer = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonRearSteer.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonRearSteerPushed, true);
            app.OverrideApplyButtonRearSteer.Tooltip = {'Overwrite SuspR.Linkage portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonRearSteer.Text = 'Apply';
            app.FrontAntiRollBarLabel = uilabel(app.ConfigureTab);
            app.FrontAntiRollBarLabel.Text = 'Front Anti Roll Bar';
            app.FrontAntiRollBarDropDown = uidropdown(app.ConfigureTab);
            app.FrontAntiRollBarDropDown.Items = {'Droplink (Hamba)', 'Droplink (HambaLG)', 'Simple', 'None', 'Droplink (Makhulu)'};
            app.FrontAntiRollBarDropDown.ItemsData = {'Droplink_Sedan_Hamba_f', 'Droplink_Sedan_HambaLG_f', 'Simple_default_f', 'None', 'Droplink_Bus_Makhulu_f'};
            app.FrontAntiRollBarDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontAntiRollBarDropDownValueChanged, true);
            app.FrontAntiRollBarDropDown.Value = 'Droplink_Sedan_Hamba_f';
            app.OverrideApplyButtonFrontAntiRollBar = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonFrontAntiRollBar.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonFrontAntiRollBarPushed, true);
            app.OverrideApplyButtonFrontAntiRollBar.Tooltip = {'Overwrite SuspF.AntiRollBar portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonFrontAntiRollBar.Text = 'Apply';
            app.RearAntiRollBarLabel = uilabel(app.ConfigureTab);
            app.RearAntiRollBarLabel.Text = 'Rear Anti Roll Bar';
            app.RearAntiRollBarDropDown = uidropdown(app.ConfigureTab);
            app.RearAntiRollBarDropDown.Items = {'Droplink (Hamba)', 'Droplink (HambaLG)', 'Simple', 'None', 'Droplink (Makhulu)'};
            app.RearAntiRollBarDropDown.ItemsData = {'Droplink_Sedan_Hamba_r', 'Droplink_Sedan_HambaLG_r', 'Simple_default_r', 'None', 'Droplink_Bus_Makhulu_r'};
            app.RearAntiRollBarDropDown.ValueChangedFcn = createCallbackFcn(app, @RearAntiRollBarDropDownValueChanged, true);
            app.RearAntiRollBarDropDown.Value = 'Droplink_Sedan_Hamba_r';
            app.OverrideApplyButtonRearAntiRollBar = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonRearAntiRollBar.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonRearAntiRollBarPushed, true);
            app.OverrideApplyButtonRearAntiRollBar.Tooltip = {'Overwrite SuspR.AntiRollBar portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonRearAntiRollBar.Text = 'Apply';
            app.BodyOpaqueCheckBox = uicheckbox(app.ConfigureTab);
            app.BodyOpaqueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BodyOpaqueCheckBoxValueChanged, true);
            app.BodyOpaqueCheckBox.Text = 'Opaque';
            app.CreateDataStructureButton = uibutton(app.ConfigureTab, 'push');
            app.CreateDataStructureButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDataStructureButtonPushed, true);
            app.CreateDataStructureButton.Tooltip = {'Create Vehicle structure based on selected options.'};
            app.CreateDataStructureButton.Text = 'Create Data Structure';
            app.OverrideApplyButtonAero = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonAero.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonAeroPushed, true);
            app.OverrideApplyButtonAero.Tooltip = {'Overwrite Aero portion in Vehicle data structure to match selection.'};
            app.OverrideApplyButtonAero.Text = 'Apply';
            app.OverrideApplyButtonBody = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonBody.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonBodyPushed, true);
            app.OverrideApplyButtonBody.Tooltip = {'Overwrite Body and BodyGeometry portions of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonBody.Text = 'Apply';
            app.PeopleLabel = uilabel(app.ConfigureTab);
            app.PeopleLabel.Text = 'People';
            app.PeopleFLCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleFLCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleFLCheckBoxValueChanged, true);
            app.PeopleFLCheckBox.Text = 'FL';
            app.PeopleFRCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleFRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleFRCheckBoxValueChanged, true);
            app.PeopleFRCheckBox.Text = 'FR';
            app.PeopleRLCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleRLCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleRLCheckBoxValueChanged, true);
            app.PeopleRLCheckBox.Text = 'RL';
            app.PeopleRRCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleRRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleRRCheckBoxValueChanged, true);
            app.PeopleRRCheckBox.Text = 'RR';
            app.PeopleDriverCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleDriverCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleDriverCheckBoxValueChanged, true);
            app.PeopleDriverCheckBox.Text = 'Driver';
            app.OverrideApplyButtonPeople = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonPeople.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonPeoplePushed, true);
            app.OverrideApplyButtonPeople.Tooltip = {'Overwrite portions of Vehicle data structure that pertain to people (DriverHuman'; ' Passenger).'};
            app.OverrideApplyButtonPeople.Text = 'Apply';
            app.HelpLabelConfigure = uilabel(app.ConfigureTab);
            app.HelpLabelConfigure.Tooltip = {'These buttons let you alter individual subsystems within the vehicle model. '; ''; '1. Set dropdown menu for any part of the vehicle.'; '2. Press the "Apply" button on same row to modify data structure in workspace.  '; '3. Press "Configure Model" in the lower right corner to apply changes to model.'};
            app.HelpLabelConfigure.Text = '(?)';
            app.PowerActiveCoolingCheckBox = uicheckbox(app.ConfigureTab);
            app.PowerActiveCoolingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerActiveCoolingCheckBoxValueChanged, true);
            app.PowerActiveCoolingCheckBox.Tooltip = {'Enable or disable cooling (electric power variants only).'};
            app.PowerActiveCoolingCheckBox.Text = 'Active Cooling';
            app.FrontTirePanel = uipanel(app.ConfigureTab);
            app.FrontTireModelDropDown = uidropdown(app.FrontTirePanel);
            app.FrontTireModelDropDown.Items = {'MFEval', 'Delft', 'MF-Swift', 'MFEval 2x', 'Delft 2x', 'MF-Swift 2x'};
            app.FrontTireModelDropDown.ItemsData = {'MFEval', 'Delft', 'MFSwift', 'MFEval_2x', 'Delft_2x', 'MFSwift_2X'};
            app.FrontTireModelDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontTireModelDropDownValueChanged, true);
            app.FrontTireModelDropDown.Value = 'MFEval';
            app.OverrideApplyButtonFrontTires = uibutton(app.FrontTirePanel, 'push');
            app.OverrideApplyButtonFrontTires.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonFrontTiresPushed, true);
            app.OverrideApplyButtonFrontTires.Tooltip = {'Overwrite TireF portion of Vehicle data structure to match selections.'};
            app.OverrideApplyButtonFrontTires.Text = 'Apply';
            app.TireDynamicsLabel = uilabel(app.FrontTirePanel);
            app.TireDynamicsLabel.Text = 'Dynamics';
            app.TireModelLabel = uilabel(app.FrontTirePanel);
            app.TireModelLabel.Text = 'Tire Model';
            app.TireSizeLabel = uilabel(app.FrontTirePanel);
            app.TireSizeLabel.Text = 'Size';
            app.TireBodyLabel = uilabel(app.FrontTirePanel);
            app.TireBodyLabel.Text = 'Body';
            app.Label = uilabel(app.FrontTirePanel);
            app.Label.Text = '';
            app.FrontTireDynamicsDropDown = uidropdown(app.FrontTirePanel);
            app.FrontTireDynamicsDropDown.Items = {'Steady-State', 'Linear Transients', 'Nonlinear Transients', 'Rigid Ring'};
            app.FrontTireDynamicsDropDown.ItemsData = {'steady', 'lintra', 'nonlin', 'ring'};
            app.FrontTireDynamicsDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontTireDynamicsDropDownValueChanged, true);
            app.FrontTireDynamicsDropDown.Value = 'steady';
            app.Label_3 = uilabel(app.FrontTirePanel);
            app.Label_3.Text = '';
            app.FrontTireDataDropDown = uidropdown(app.FrontTirePanel);
            app.FrontTireDataDropDown.Items = {'213-40R21', '235-50R24', '270-70R22'};
            app.FrontTireDataDropDown.ItemsData = {'213_40R21', '235_50R24', '270_70R22'};
            app.FrontTireDataDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontTireDataDropDownValueChanged, true);
            app.FrontTireDataDropDown.Value = '213_40R21';
            app.Label_5 = uilabel(app.FrontTirePanel);
            app.Label_5.Text = '';
            app.FrontTireBodyDropDown = uidropdown(app.FrontTirePanel);
            app.FrontTireBodyDropDown.Items = {'CAD', 'Generic'};
            app.FrontTireBodyDropDown.ItemsData = {'CAD', 'Generic'};
            app.FrontTireBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontTireBodyDropDownValueChanged, true);
            app.FrontTireBodyDropDown.Value = 'CAD';
            app.SlipLabel = uilabel(app.FrontTirePanel);
            app.SlipLabel.Text = 'Slip';
            app.FrontTireSlipDropDown = uidropdown(app.FrontTirePanel);
            app.FrontTireSlipDropDown.Items = {'Longitudinal', 'Lateral', 'Uncombined', 'Combined', 'Combined+Turnslip', 'None'};
            app.FrontTireSlipDropDown.ItemsData = {'longitudinal', 'lateral', 'uncombined', 'combined', 'combined+turnslip', 'none'};
            app.FrontTireSlipDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontTireSlipDropDownValueChanged, true);
            app.FrontTireSlipDropDown.Value = 'combined';
            app.ContactLabel = uilabel(app.FrontTirePanel);
            app.ContactLabel.Text = 'Contact';
            app.FrontTireContactDropDown = uidropdown(app.FrontTirePanel);
            app.FrontTireContactDropDown.Items = {'Smooth', 'Enveloping'};
            app.FrontTireContactDropDown.ItemsData = {'smooth', 'enveloping'};
            app.FrontTireContactDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontTireContactDropDownValueChanged, true);
            app.FrontTireContactDropDown.Value = 'smooth';
            app.FrontTireLabel = uilabel(app.FrontTirePanel);
            app.FrontTireLabel.Text = 'Front';
            app.Panel = uipanel(app.ConfigureTab);
            app.RearTireModelDropDown = uidropdown(app.Panel);
            app.RearTireModelDropDown.Items = {'MFEval', 'Delft', 'MF-Swift', 'MFEval 2x', 'Delft 2x', 'MF-Swift 2x'};
            app.RearTireModelDropDown.ItemsData = {'MFEval', 'Delft', 'MFSwift', 'MFEval_2x', 'Delft_2x', 'MFSwift_2x'};
            app.RearTireModelDropDown.ValueChangedFcn = createCallbackFcn(app, @RearTireModelDropDownValueChanged, true);
            app.RearTireModelDropDown.Value = 'MFEval';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.RearTireDataDropDown = uidropdown(app.Panel);
            app.RearTireDataDropDown.Items = {'213-40R21', '235-50R24', '270-70R22'};
            app.RearTireDataDropDown.ItemsData = {'213_40R21', '235_50R24', '270_70R22'};
            app.RearTireDataDropDown.ValueChangedFcn = createCallbackFcn(app, @RearTireDataDropDownValueChanged, true);
            app.RearTireDataDropDown.Value = '213_40R21';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.RearTireBodyDropDown = uidropdown(app.Panel);
            app.RearTireBodyDropDown.Items = {'CAD', 'Generic'};
            app.RearTireBodyDropDown.ItemsData = {'CAD', 'Generic'};
            app.RearTireBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @RearTireBodyDropDownValueChanged, true);
            app.RearTireBodyDropDown.Value = 'CAD';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.RearTireDynamicsDropDown = uidropdown(app.Panel);
            app.RearTireDynamicsDropDown.Items = {'Steady-State', 'Linear Transients', 'Nonlinear Transients', 'Rigid Ring'};
            app.RearTireDynamicsDropDown.ItemsData = {'steady', 'lintra', 'nonlin', 'ring'};
            app.RearTireDynamicsDropDown.ValueChangedFcn = createCallbackFcn(app, @RearTireDynamicsDropDownValueChanged, true);
            app.RearTireDynamicsDropDown.Value = 'steady';
            app.OverrideApplyButtonRearTires = uibutton(app.Panel, 'push');
            app.OverrideApplyButtonRearTires.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonRearTiresPushed, true);
            app.OverrideApplyButtonRearTires.Tooltip = {'Overwrite TireR portion of Vehicle data structure to match selections.'};
            app.OverrideApplyButtonRearTires.Text = 'Apply';
            app.SlipLabel_2 = uilabel(app.Panel);
            app.SlipLabel_2.Text = 'Slip';
            app.RearTireSlipDropDown = uidropdown(app.Panel);
            app.RearTireSlipDropDown.Items = {'Longitudinal', 'Lateral', 'Uncombined', 'Combined', 'Combined+Turnslip', 'None'};
            app.RearTireSlipDropDown.ItemsData = {'longitudinal', 'lateral', 'uncombined', 'combined', 'combined+turnslip', 'none'};
            app.RearTireSlipDropDown.ValueChangedFcn = createCallbackFcn(app, @RearTireSlipDropDownValueChanged, true);
            app.RearTireSlipDropDown.Value = 'combined';
            app.ContactLabel_2 = uilabel(app.Panel);
            app.ContactLabel_2.Text = 'Contact';
            app.RearTireContactDropDown = uidropdown(app.Panel);
            app.RearTireContactDropDown.Items = {'Smooth', 'Enveloping'};
            app.RearTireContactDropDown.ItemsData = {'smooth', 'enveloping'};
            app.RearTireContactDropDown.ValueChangedFcn = createCallbackFcn(app, @RearTireContactDropDownValueChanged, true);
            app.RearTireContactDropDown.Value = 'smooth';
            app.RearLabel = uilabel(app.Panel);
            app.RearLabel.Text = 'Rear';
            app.MatrixTab = uitab(app.TabGroup);
            app.MatrixTab.Title = 'Matrix';
            app.ConfigurationsButtonGroup = uibuttongroup(app.MatrixTab);
            app.ConfigurationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MatrixConfigSelectionChanged2, true);
            app.ConfigurationsButtonGroup.Tooltip = {'  '};
            app.ConfigurationsButtonGroup.Title = 'Configurations';
            app.Button = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button.Text = '000';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_2.Text = '001';
            app.Button_3 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_3.Text = '002';
            app.Button_4 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_4.Text = '003';
            app.Button_5 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_5.Text = '004';
            app.Button_6 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_6.Text = '005';
            app.Button_7 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_7.Text = '006';
            app.Button_8 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_8.Text = '007';
            app.Button_9 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_9.Text = '008';
            app.Button_10 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_10.Text = '009';
            app.Button_11 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_11.Text = '010';
            app.Button_12 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_12.Text = '011';
            app.Button_13 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_13.Text = '012';
            app.Button_14 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_14.Text = '013';
            app.Button_15 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_15.Text = '014';
            app.Button_16 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_16.Text = '015';
            app.Button_17 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_17.Text = '016';
            app.Button_18 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_18.Text = '017';
            app.Button_19 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_19.Text = '018';
            app.Button_20 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_20.Text = '019';
            app.Button_21 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_21.Text = '020';
            app.Button_22 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_22.Text = '021';
            app.Button_23 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_23.Text = '022';
            app.Button_24 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_24.Text = '023';
            app.Button_25 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_25.Text = '024';
            app.Button_26 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_26.Text = '025';
            app.Button_27 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_27.Text = '026';
            app.Button_28 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_28.Text = '027';
            app.Button_29 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_29.Text = '028';
            app.Button_30 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_30.Text = '029';
            app.Button_31 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_31.Text = '030';
            app.Button_32 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_32.Text = '031';
            app.Button_33 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_33.Text = '032';
            app.Button_34 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_34.Text = '033';
            app.Button_35 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_35.Text = '034';
            app.Button_36 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_36.Text = '035';
            app.Button_37 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_37.Text = '036';
            app.Button_38 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_38.Text = '037';
            app.Button_39 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_39.Text = '038';
            app.Button_40 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_40.Text = '039';
            app.Button_41 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_41.Text = '040';
            app.Button_42 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_42.Text = '041';
            app.Button_43 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_43.Text = '042';
            app.Button_44 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_44.Text = '043';
            app.Button_45 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_45.Text = '044';
            app.Button_46 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_46.Text = '045';
            app.Button_47 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_47.Text = '046';
            app.Button_48 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_48.Text = '047';
            app.Button_49 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_49.Text = '048';
            app.Button_50 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_50.Text = '049';
            app.Button_51 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_51.Text = '050';
            app.Button_52 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_52.Text = '051';
            app.Button_53 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_53.Text = '052';
            app.Button_54 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_54.Text = '053';
            app.Button_55 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_55.Text = '054';
            app.Button_56 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_56.Text = '055';
            app.Button_57 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_57.Text = '056';
            app.Button_58 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_58.Text = '057';
            app.Button_59 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_59.Text = '058';
            app.Button_60 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_60.Text = '059';
            app.Button_61 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_61.Text = '060';
            app.Button_62 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_62.Text = '061';
            app.Button_63 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_63.Text = '062';
            app.Button_64 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_64.Text = '063';
            app.Button_65 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_65.Text = '064';
            app.Button_66 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_66.Text = '065';
            app.Button_67 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_67.Text = '066';
            app.Button_68 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_68.Text = '067';
            app.Button_69 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_69.Text = '068';
            app.Button_70 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_70.Text = '069';
            app.Button_71 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_71.Text = '070';
            app.Button_72 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_72.Text = '071';
            app.Button_73 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_73.Text = '072';
            app.Button_74 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_74.Text = '073';
            app.Button_75 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_75.Text = '074';
            app.Button_76 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_76.Text = '075';
            app.Button_77 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_77.Text = '076';
            app.Button_78 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_78.Text = '077';
            app.Button_79 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_79.Text = '078';
            app.Button_80 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_80.Text = '079';
            app.Button_81 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_81.Text = '080';
            app.Button_82 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_82.Text = '081';
            app.Button_83 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_83.Text = '082';
            app.Button_84 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_84.Text = '083';
            app.Button_85 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_85.Text = '084';
            app.Button_86 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_86.Text = '085';
            app.Button_87 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_87.Text = '086';
            app.Button_88 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_88.Text = '087';
            app.Button_89 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_89.Text = '088';
            app.Button_90 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_90.Text = '089';
            app.Button_91 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_91.Text = '090';
            app.Button_92 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_92.Text = '091';
            app.Button_93 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_93.Text = '092';
            app.Button_94 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_94.Text = '093';
            app.Button_95 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_95.Text = '094';
            app.Button_96 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_96.Text = '095';
            app.Button_97 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_97.Text = '096';
            app.Button_98 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_98.Text = '097';
            app.Button_99 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_99.Text = '098';
            app.Button_100 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_100.Text = '099';
            app.Button_101 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_101.Text = '100';
            app.Button_102 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_102.Text = '101';
            app.Button_103 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_103.Text = '102';
            app.Button_104 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_104.Text = '103';
            app.Button_105 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_105.Text = '104';
            app.Button_106 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_106.Text = '105';
            app.Button_107 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_107.Text = '106';
            app.Button_108 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_108.Text = '107';
            app.Button_109 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_109.Text = '108';
            app.Button_110 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_110.Text = '109';
            app.Button_111 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_111.Text = '110';
            app.Button_112 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_112.Text = '111';
            app.Button_113 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_113.Text = '112';
            app.Button_114 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_114.Text = '113';
            app.Button_115 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_115.Text = '114';
            app.Button_116 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_116.Text = '115';
            app.Button_117 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_117.Text = '116';
            app.Button_118 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_118.Text = '117';
            app.Button_119 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_119.Text = '118';
            app.Button_120 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_120.Text = '119';
            app.Button_121 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_121.Text = '120';
            app.Button_122 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_122.Text = '121';
            app.Button_123 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_123.Text = '122';
            app.Button_124 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_124.Text = '123';
            app.Button_125 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_125.Text = '124';
            app.Button_126 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_126.Text = '125';
            app.Button_127 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_127.Text = '126';
            app.Button_128 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_128.Text = '127';
            app.MS1Label = uilabel(app.MatrixTab);
            app.MS1Label.Text = 'MS1';
            app.MSDLabel = uilabel(app.MatrixTab);
            app.MSDLabel.Text = 'MSD';
            app.MS3Label = uilabel(app.MatrixTab);
            app.MS3Label.Text = 'MS3';
            app.MSFLabel = uilabel(app.MatrixTab);
            app.MSFLabel.Text = 'MSF';
            app.ML1Label = uilabel(app.MatrixTab);
            app.ML1Label.Text = 'ML1';
            app.MLDLabel = uilabel(app.MatrixTab);
            app.MLDLabel.Text = 'MLD';
            app.ML3Label = uilabel(app.MatrixTab);
            app.ML3Label.Text = 'ML3';
            app.MLFLabel = uilabel(app.MatrixTab);
            app.MLFLabel.Text = 'MLF';
            app.SS1Label = uilabel(app.MatrixTab);
            app.SS1Label.Text = 'SS1';
            app.SSDLabel = uilabel(app.MatrixTab);
            app.SSDLabel.Text = 'SSD';
            app.SS3Label = uilabel(app.MatrixTab);
            app.SS3Label.Text = 'SS3';
            app.SSFLabel = uilabel(app.MatrixTab);
            app.SSFLabel.Text = 'SSF';
            app.SL1Label = uilabel(app.MatrixTab);
            app.SL1Label.Text = 'SL1';
            app.SLDLabel = uilabel(app.MatrixTab);
            app.SLDLabel.Text = 'SLD';
            app.SL3Label = uilabel(app.MatrixTab);
            app.SL3Label.Text = 'SL3';
            app.SLFLabel = uilabel(app.MatrixTab);
            app.SLFLabel.Text = 'SLF';
            app.DWLabel = uilabel(app.MatrixTab);
            app.DWLabel.Text = 'DW';
            app.DWaLabel = uilabel(app.MatrixTab);
            app.DWaLabel.Tooltip = {'Double wishbone suspension with no steering possible (upright cannot rotate about its vertical axis).'};
            app.DWaLabel.Text = 'DWa';
            app.S2LAFLabel = uilabel(app.MatrixTab);
            app.S2LAFLabel.Tooltip = {'Split lower arm with shock attached to front lower arm.'};
            app.S2LAFLabel.Text = 'S2LAF';
            app.S2LARLabel = uilabel(app.MatrixTab);
            app.S2LARLabel.Tooltip = {'Split lower arm with shock attached to lower rear arm.'};
            app.S2LARLabel.Text = 'S2LAR';
            app.S25LAFLabel = uilabel(app.MatrixTab);
            app.S25LAFLabel.Tooltip = {'5 link suspension with shock attached to lower front arm.'};
            app.S25LAFLabel.Text = '5S2LAF';
            app.S25LARLabel = uilabel(app.MatrixTab);
            app.S25LARLabel.Tooltip = {'5 link suspension with shock attached to lower rear arm.'};
            app.S25LARLabel.Text = '5S2LAR';
            app.CS52LAFLabel = uilabel(app.MatrixTab);
            app.CS52LAFLabel.Tooltip = {'5 link suspension with shock attached to lower front arm.  Suspension implemented with constraints resulting in fewer states.'};
            app.CS52LAFLabel.Text = '5CS2LAF';
            app.DOFLabel = uilabel(app.MatrixTab);
            app.DOFLabel.Tooltip = {'Simple suspension resulting in a 15 degree of freedom model.  Axles have heave and roll degrees of freedom only.'};
            app.DOFLabel.Text = '15DOF';
            app.AbbreviationLabels = uilabel(app.MatrixTab);
            app.AbbreviationLabels.Text = 'MF / Swift      Steady State / Linear Transient    1D/1D3D/3D/3DFlex';
            app.HelpLabelMatrix = uilabel(app.MatrixTab);
            app.HelpLabelMatrix.Tooltip = {'Selecting a preset below updates the Vehicle data structure in the MATLAB workspace and triggers variant selection in the model.  '; ''; 'Columns are for front suspension option. '; 'Rows are for tire model | tire options | driveline options.'};
            app.HelpLabelMatrix.Text = '(?)';
            app.RowabbreviationindicatestireanddrivelineconfigurationLabel = uilabel(app.MatrixTab);
            app.RowabbreviationindicatestireanddrivelineconfigurationLabel.Text = 'Row abbreviation indicates tire and driveline configuration';
            app.EventTab = uitab(app.TabGroup);
            app.EventTab.Title = 'Event';
            app.ManeuverLabel = uilabel(app.EventTab);
            app.ManeuverLabel.Text = 'Maneuver';
            app.ManeuverDropDown = uidropdown(app.EventTab);
            app.ManeuverDropDown.Items = {'WOT Braking', 'Low-Speed Steer', 'Turn', 'Ice Patch', 'RDF Rough Road', 'Rough Road Z Only', 'RDF Plateau', 'Plateau Z Only', 'CRG Plateau', 'Straight Constant Speed', 'Constant Radius', 'Drive Cycle FTP75', 'Drive Cycle UrbanCycle1', 'Trailer Disturbance', 'Double Lane Change', 'Mallory Park', 'Mallory Park CCW', 'Mallory Park Obstacle', 'MCity', 'CRG Mallory Park', 'CRG Mallory Park (flat)', 'CRG Kyalami', 'CRG Kyalami (flat)', 'CRG Nurburgring N', 'CRG Nurburgring N (flat)', 'CRG Suzuka', 'CRG Suzuka (flat)', 'CRG Pikes Peak (up)', 'CRG Pikes Peak (down)', 'CRG Custom', 'CRG Custom (flat)', 'Skidpad', 'Testrig 4 Post'};
            app.ManeuverDropDown.ItemsData = {'WOT Braking', 'Low Speed Steer', 'Turn', 'Ice Patch', 'RDF Rough Road', 'Rough Road Z Only', 'RDF Plateau', 'Plateau Z Only', 'CRG Plateau', 'Straight Constant Speed', 'Constant Radius Closed-Loop', 'Drive Cycle FTP75', 'Drive Cycle UrbanCycle1', 'Trailer Disturbance', 'Double Lane Change', 'Mallory Park', 'Mallory Park CCW', 'Mallory Park Obstacle', 'MCity', 'CRG Mallory Park', 'CRG Mallory Park F', 'CRG Kyalami', 'CRG Kyalami F', 'CRG Nurburgring N', 'CRG Nurburgring N F', 'CRG Suzuka', 'CRG Suzuka F', 'CRG Pikes Peak', 'CRG Pikes Peak Down', 'CRG Custom', 'CRG Custom F', 'Skidpad', 'Testrig 4 Post'};
            app.ManeuverDropDown.ValueChangedFcn = createCallbackFcn(app, @ManeuverDropDownValueChanged, true);
            app.ManeuverDropDown.Tooltip = {'Sets surface.  Sets initial state for vehicle and trailer if present.  Sets driver type.'};
            app.ManeuverDropDown.Value = 'WOT Braking';
            app.SurfaceDropDownLabel = uilabel(app.EventTab);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.EventTab);
            app.SurfaceDropDown.Items = {'Plane Grid', 'Road Two Lane', 'Double Lane Change', 'Ice Patch', 'CRG Slope', 'Track Mallory Park'};
            app.SurfaceDropDown.ItemsData = {'Plane Grid', 'Road Two Lane', 'Double Lane Change', 'Ice Patch', 'CRG Slope', 'Track Mallory Park'};
            app.SurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceDropDownValueChanged, true);
            app.SurfaceDropDown.Tooltip = {'Selects visualized surface in the model.  Certain surfaces will modify tire parameters.'};
            app.SurfaceDropDown.Value = 'Plane Grid';
            app.SolverDropDownLabel = uilabel(app.EventTab);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.EventTab);
            app.SolverDropDown.Items = {'Variable Step', 'Fixed Step', 'Fixed Step (Simulink Real-Time)'};
            app.SolverDropDown.ItemsData = {'Variable Step', 'Fixed Step', 'Fixed Step slrt'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Tooltip = {'Configures solvers for variable/fixed step simulation.'};
            app.SolverDropDown.Value = 'Variable Step';
            app.PlotManeuverButton = uibutton(app.EventTab, 'push');
            app.PlotManeuverButton.ButtonPushedFcn = createCallbackFcn(app, @PlotManeuverButtonPushed, true);
            app.PlotManeuverButton.Tooltip = {'Plot inputs to driver (open loop commands or trajectory).'};
            app.PlotManeuverButton.Text = 'Plot Maneuver';
            app.PointLabelIntervalEditFieldLabel = uilabel(app.EventTab);
            app.PointLabelIntervalEditFieldLabel.Text = 'Point Label Interval';
            app.PointLabelIntervalEditField = uieditfield(app.EventTab, 'numeric');
            app.PointLabelIntervalEditField.Tooltip = {'Control number of labeled points on maneuver plot.'};
            app.DriverDropDownLabel = uilabel(app.EventTab);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DriverDropDown = uidropdown(app.EventTab);
            app.DriverDropDown.Items = {'Open Loop', 'Closed Loop', 'Interactive'};
            app.DriverDropDown.ItemsData = {'Open Loop', 'Closed Loop', 'Interactive'};
            app.DriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DriverDropDownValueChanged, true);
            app.DriverDropDown.Tooltip = {'Configure driver for open or closed loop behavior.'};
            app.DriverDropDown.Value = 'Open Loop';
            app.ShowPaceCarInteractiveCheckBox = uicheckbox(app.EventTab);
            app.ShowPaceCarInteractiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPaceCarInteractiveCheckBoxValueChanged, true);
            app.ShowPaceCarInteractiveCheckBox.Tooltip = {'Enable pace car to be shown when interactive driving is selected.'};
            app.ShowPaceCarInteractiveCheckBox.Text = 'Show Pace Car (Interactive)';
            app.IndividualSettingsLabel = uilabel(app.EventTab);
            app.IndividualSettingsLabel.Text = 'Individual Settings';
            app.ManeuverCodeButton = uibutton(app.EventTab, 'push');
            app.ManeuverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuverCodeButtonPushed, true);
            app.ManeuverCodeButton.Tooltip = {'Edit code that configures workspace and model for maneuver.'};
            app.ManeuverCodeButton.Text = 'Code';
            app.PlotManeuverCodeButton = uibutton(app.EventTab, 'push');
            app.PlotManeuverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotManeuverCodeButtonPushed, true);
            app.PlotManeuverCodeButton.Tooltip = {'Edit code that plots inputs for maneuver.'};
            app.PlotManeuverCodeButton.Text = 'Code';
            app.SurfaceCodeButton = uibutton(app.EventTab, 'push');
            app.SurfaceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceCodeButtonPushed, true);
            app.SurfaceCodeButton.Tooltip = {'Edit code that configures the scene.'};
            app.SurfaceCodeButton.Text = 'Code';
            app.SolverCodeButton = uibutton(app.EventTab, 'push');
            app.SolverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SolverCodeButtonPushed, true);
            app.SolverCodeButton.Tooltip = {'Edit code that configures solver settings.'};
            app.SolverCodeButton.Text = 'Code';
            app.WindLabel = uilabel(app.EventTab);
            app.WindLabel.Text = 'Wind';
            app.WindCarCheckBox = uicheckbox(app.EventTab);
            app.WindCarCheckBox.Tooltip = {'Enables gust of wind to car.  Press apply to configure the model.'};
            app.WindCarCheckBox.Text = 'Car';
            app.WindTrailerCheckBox = uicheckbox(app.EventTab);
            app.WindTrailerCheckBox.Tooltip = {'Enables gust of wind to trailer.  Press apply to configure the model.'};
            app.WindTrailerCheckBox.Text = 'Trailer';
            app.OverrideWindButton = uibutton(app.EventTab, 'push');
            app.OverrideWindButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideWindButtonPushed, true);
            app.OverrideWindButton.Tooltip = {'Enable/Disable gust of wind applied to trailer and car per checkboxes.'};
            app.OverrideWindButton.Text = 'Apply';
            app.WindCodeButton = uibutton(app.EventTab, 'push');
            app.WindCodeButton.ButtonPushedFcn = createCallbackFcn(app, @WindCodeButtonPushed, true);
            app.WindCodeButton.Tooltip = {'Edit code that configures settings for wind in the model.'};
            app.WindCodeButton.Text = 'Code';
            app.SelectManeuverLabel = uilabel(app.EventTab);
            app.SelectManeuverLabel.Text = 'Select Maneuver';
            app.DefineNewTrajectoryButton = uibutton(app.EventTab, 'push');
            app.DefineNewTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DefineNewTrajectoryButtonPushed, true);
            app.DefineNewTrajectoryButton.Enable = 'off';
            app.DefineNewTrajectoryButton.Tooltip = {'(MCity maneuver only R2020 and higher) Use UI to define new maneuver.'};
            app.DefineNewTrajectoryButton.Text = 'Define New Trajectory';
            app.UnrealEngineAnimationCheckBox = uicheckbox(app.EventTab);
            app.UnrealEngineAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnrealEngineAnimationCheckBoxValueChanged, true);
            app.UnrealEngineAnimationCheckBox.Tooltip = {'Enable animation in Unreal Engine during simulation of main model sm_car.'};
            app.UnrealEngineAnimationCheckBox.Text = 'Unreal Engine Animation';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PositionandSpeedButton = uibutton(app.ResultsTab, 'push');
            app.PositionandSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @PositionandSpeedButtonPushed, true);
            app.PositionandSpeedButton.Tooltip = {'Plots vehicle position and speed.'};
            app.PositionandSpeedButton.Text = 'Position and Speed';
            app.PlotsLabel = uilabel(app.ResultsTab);
            app.PlotsLabel.Text = 'Plots';
            app.WheelSpeedsButton = uibutton(app.ResultsTab, 'push');
            app.WheelSpeedsButton.ButtonPushedFcn = createCallbackFcn(app, @WheelSpeedsButtonPushed, true);
            app.WheelSpeedsButton.Tooltip = {'Plot of vehicle wheel speeds.'};
            app.WheelSpeedsButton.Text = 'Wheel Speeds';
            app.ManeuverButton = uibutton(app.ResultsTab, 'push');
            app.ManeuverButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuverButtonPushed, true);
            app.ManeuverButton.Tooltip = {'Open-loop maneuver: plots pedal and steering measurements over commands.  '; 'Closed-loop maneuver: compares path velocity and yaw angle to desired trajectory'};
            app.ManeuverButton.Text = 'Maneuver';
            app.ReplayinUnrealButton = uibutton(app.ResultsTab, 'push');
            app.ReplayinUnrealButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayinUnrealButtonPushed, true);
            app.ReplayinUnrealButton.Tooltip = {'Open model for replaying simulation results in Unreal animation.'};
            app.ReplayinUnrealButton.Text = 'Replay in Unreal';
            app.AnimationLabel = uilabel(app.ResultsTab);
            app.AnimationLabel.Text = 'Animation';
            app.PositionandSpeedCodeButton = uibutton(app.ResultsTab, 'push');
            app.PositionandSpeedCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionandSpeedCodeButtonPushed, true);
            app.PositionandSpeedCodeButton.Tooltip = {'Edit code that plots position'; ' speed'; ' and other measures.'};
            app.PositionandSpeedCodeButton.Text = 'Code';
            app.WheelSpeedsCodeButton = uibutton(app.ResultsTab, 'push');
            app.WheelSpeedsCodeButton.ButtonPushedFcn = createCallbackFcn(app, @WheelSpeedsCodeButtonPushed, true);
            app.WheelSpeedsCodeButton.Tooltip = {'Edit code that plots wheel speeds.'};
            app.WheelSpeedsCodeButton.Text = 'Code';
            app.ResultsManeuverCodeButton = uibutton(app.ResultsTab, 'push');
            app.ResultsManeuverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsManeuverCodeButtonPushed, true);
            app.ResultsManeuverCodeButton.Tooltip = {'Edit code that plots results of maneuver.'};
            app.ResultsManeuverCodeButton.Text = 'Code';
            app.BodyMeasurementsButton = uibutton(app.ResultsTab, 'push');
            app.BodyMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @BodyMeasurementsButtonPushed, true);
            app.BodyMeasurementsButton.Tooltip = {'Plots body roll pitch and yaw.'};
            app.BodyMeasurementsButton.Text = 'Body Measurements';
            app.BodyMeasurementsCodeButton = uibutton(app.ResultsTab, 'push');
            app.BodyMeasurementsCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BodyMeasurementsCodeButtonPushed, true);
            app.BodyMeasurementsCodeButton.Tooltip = {'Edit code that plots body measurements.'};
            app.BodyMeasurementsCodeButton.Text = 'Code';
            app.SimscapeResultsExplorerButton = uibutton(app.ResultsTab, 'push');
            app.SimscapeResultsExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @SimscapeResultsExplorerButtonPushed, true);
            app.SimscapeResultsExplorerButton.Tooltip = {'Open Simscape Results Explorer with results of last simulation.'};
            app.SimscapeResultsExplorerButton.Text = 'Simscape Results Explorer';
            app.SimulationDataInspectorButton = uibutton(app.ResultsTab, 'push');
            app.SimulationDataInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationDataInspectorButtonPushed, true);
            app.SimulationDataInspectorButton.Tooltip = {'Open Simulation Data Inspector.'};
            app.SimulationDataInspectorButton.Text = 'Simulation Data Inspector';
            app.PowertrainCurrentButton = uibutton(app.ResultsTab, 'push');
            app.PowertrainCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PowertrainCurrentButtonPushed, true);
            app.PowertrainCurrentButton.Tooltip = {'Plots currents from electrical power supplies.'};
            app.PowertrainCurrentButton.Text = 'Powertrain Current';
            app.PowertrainCurrentCodeButton = uibutton(app.ResultsTab, 'push');
            app.PowertrainCurrentCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PowertrainCurrentCodeButtonPushed, true);
            app.PowertrainCurrentCodeButton.Tooltip = {'Edit code that plots electric currents.'};
            app.PowertrainCurrentCodeButton.Text = 'Code';
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.RegenerateVehiclePresetsButton = uibutton(app.MainTab, 'push');
            app.RegenerateVehiclePresetsButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateVehiclePresetsButtonPushed, true);
            app.RegenerateVehiclePresetsButton.Tooltip = {'Create Vehicle_###.mat files containing preset configurations.'};
            app.RegenerateVehiclePresetsButton.Text = 'Regenerate';
            app.RegenerateVehicleDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateVehicleDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateVehicleDatabaseButtonPushed, true);
            app.RegenerateVehicleDatabaseButton.Tooltip = {'Create VDatabase structure with all options for Vehicle model by reloading all Excel files (unchanged modified and new).'};
            app.RegenerateVehicleDatabaseButton.Text = 'Regenerate';
            app.RegenerateManueverDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateManueverDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateManueverDatabaseButtonPushed, true);
            app.RegenerateManueverDatabaseButton.Tooltip = {'Create MDatabase with settings for vehicle manuevers by loading from Excel.'};
            app.RegenerateManueverDatabaseButton.Text = 'Regenerate';
            app.RegenerateDatabasesinMATLABWorkspaceLabel = uilabel(app.MainTab);
            app.RegenerateDatabasesinMATLABWorkspaceLabel.Text = 'Regenerate Databases in MATLAB Workspace';
            app.RegenerateInitDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateInitDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateInitDatabaseButtonPushed, true);
            app.RegenerateInitDatabaseButton.Tooltip = {'Create IDatabase structure with possible initial conditions for vehicle by loading data from Excel.'};
            app.RegenerateInitDatabaseButton.Text = 'Regenerate';
            app.RegenerateDriverDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateDriverDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateDriverDatabaseButtonPushed, true);
            app.RegenerateDriverDatabaseButton.Tooltip = {'Create DDatabase structure with parameters for driver models by loading data from Excel.'};
            app.RegenerateDriverDatabaseButton.Text = 'Regenerate';
            app.RegenerateSceneDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateSceneDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateSceneDatabaseButtonPushed, true);
            app.RegenerateSceneDatabaseButton.Tooltip = {'Create Scene structure with data for scene elements.'};
            app.RegenerateSceneDatabaseButton.Text = 'Regenerate';
            app.RegenerateCameraDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateCameraDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateCameraDatabaseButtonPushed, true);
            app.RegenerateCameraDatabaseButton.Tooltip = {'Create CDatabase structure with viewpoint locations in 3D animation by loading data from Excel.'};
            app.RegenerateCameraDatabaseButton.Text = 'Regenerate';
            app.VehicleDatabaseLabel = uilabel(app.MainTab);
            app.VehicleDatabaseLabel.Text = 'Vehicle Database';
            app.VehiclePresetsLabel = uilabel(app.MainTab);
            app.VehiclePresetsLabel.Text = 'Vehicle Presets';
            app.ManeuverDatabaseLabel = uilabel(app.MainTab);
            app.ManeuverDatabaseLabel.Text = 'Maneuver Database';
            app.InitDatabaseLabel = uilabel(app.MainTab);
            app.InitDatabaseLabel.Text = 'Init Database';
            app.DriverDatabaseLabel = uilabel(app.MainTab);
            app.DriverDatabaseLabel.Text = 'Driver Database';
            app.SceneDatabaseLabel = uilabel(app.MainTab);
            app.SceneDatabaseLabel.Text = 'Scene Database';
            app.CameraDatabaseLabel = uilabel(app.MainTab);
            app.CameraDatabaseLabel.Text = 'Camera Database';
            app.VehiclePresetsFileButton = uibutton(app.MainTab, 'push');
            app.VehiclePresetsFileButton.ButtonPushedFcn = createCallbackFcn(app, @VehiclePresetsFileButtonPushed, true);
            app.VehiclePresetsFileButton.Tooltip = {'Edit code that generates presets.'};
            app.VehiclePresetsFileButton.Text = 'Code';
            app.ManeuverDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.ManeuverDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuverDatabaseFileButtonPushed, true);
            app.ManeuverDatabaseFileButton.Tooltip = {'Open Excel file with maneuver settings.'};
            app.ManeuverDatabaseFileButton.Text = 'Excel';
            app.InitDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.InitDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @InitDatabaseFileButtonPushed, true);
            app.InitDatabaseFileButton.Tooltip = {'Open Excel file with initial vehicle conditions.'};
            app.InitDatabaseFileButton.Text = 'Excel';
            app.DriverDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.DriverDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @DriverDatabaseFileButtonPushed, true);
            app.DriverDatabaseFileButton.Tooltip = {'Open Excel file with driver model options.'};
            app.DriverDatabaseFileButton.Text = 'Excel';
            app.SceneDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.SceneDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @SceneDatabaseFileButtonPushed, true);
            app.SceneDatabaseFileButton.Tooltip = {'Edit code that loads scene data.'};
            app.SceneDatabaseFileButton.Text = 'Code';
            app.CameraDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.CameraDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @CameraDatabaseFileButtonPushed, true);
            app.CameraDatabaseFileButton.Tooltip = {'Open Excel file with camera locations and orientations.'};
            app.CameraDatabaseFileButton.Text = 'Excel';
            app.HelpLabelMain = uilabel(app.MainTab);
            app.HelpLabelMain.Tooltip = {'Buttons to reload databases from source (Excel or other).  '; ''; 'After retrieving a new update of files it is recommended to press the "Regenerate All" button to ensure the workspace data is up to date.'};
            app.HelpLabelMain.Text = '(?)';
            app.RegenerateAllDatabasesButton = uibutton(app.MainTab, 'push');
            app.RegenerateAllDatabasesButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateAllDatabasesButtonPushed, true);
            app.RegenerateAllDatabasesButton.Tooltip = {'Regenerate all databases from source (Excel or other) and update .mat files.'};
            app.RegenerateAllDatabasesButton.Text = 'Regenerate All';
            app.RegenerateAllDatabasesFileButton = uibutton(app.MainTab, 'push');
            app.RegenerateAllDatabasesFileButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateAllDatabasesFileButtonPushed, true);
            app.RegenerateAllDatabasesFileButton.Tooltip = {'Edit code that regenerates all databases.'};
            app.RegenerateAllDatabasesFileButton.Text = 'Code';
            app.TestModelsLabel = uilabel(app.MainTab);
            app.TestModelsLabel.Text = 'Test Models';
            app.FuelCellVehicle1DButton = uibutton(app.MainTab, 'push');
            app.FuelCellVehicle1DButton.ButtonPushedFcn = createCallbackFcn(app, @FuelCellVehicle1DButtonPushed, true);
            app.FuelCellVehicle1DButton.Tooltip = {'Opens test model ssc_car_FuelCell1Motor.slx'};
            app.FuelCellVehicle1DButton.Text = 'Fuel Cell Vehicle (1D)';
            app.SimscapeVehicleTemplateConfigurationLabel = uilabel(app.UIFigure);
            app.SimscapeVehicleTemplateConfigurationLabel.Text = 'Simscape Vehicle Template Configuration';
            app.ConfigureModelButton = uibutton(app.UIFigure, 'push');
            app.ConfigureModelButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureModelButtonPushed, true);
            app.ConfigureModelButton.Tooltip = {'Trigger variant selection in model.'};
            app.ConfigureModelButton.Text = {'Configure'; 'Model'};
            app.LoadVDatabase = uibutton(app.UIFigure, 'push');
            app.LoadVDatabase.ButtonPushedFcn = createCallbackFcn(app, @LoadVDatabaseButtonPushed, true);
            app.LoadVDatabase.Tooltip = {'Update VDatabase by loading only Excel files that have been modified since VDatabase was last created.'};
            app.LoadVDatabase.Text = {'Update Vehicle'; 'Database'};
            app.ListVehicleConfigurationButton = uibutton(app.UIFigure, 'push');
            app.ListVehicleConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ListVehicleConfigurationButtonPushed, true);
            app.ListVehicleConfigurationButton.Tooltip = {'Display instances from Vehicle data structure to Command window.'};
            app.ListVehicleConfigurationButton.Text = {'List Vehicle'; 'Configuration'};
            app.OpenModelButton = uibutton(app.UIFigure, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Tooltip = {'Open the main model.'};
            app.OpenModelButton.Text = {'Open'; 'Model'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sm_car_vehcfg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end